classdef CustomPolarPlot < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        PlotAndControls   matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        ZaleneodDropDown  matlab.ui.control.DropDown
        ZaleneodLabel     matlab.ui.control.Label
        SelectPlot        matlab.ui.control.StateButton
        WybierzLabel      matlab.ui.control.Label
        TimeQualityLamp   matlab.ui.control.Lamp
        TimeQualityLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        MainWindowApp
        PolarAxes % Variable used to manage polar plot
        PolarPlot
    end
    
    
    methods (Access = public)
        
        function UpdatePolarPlot(comp,Value,Angle)
            comp.PolarPlot=polarplot(comp.PolarAxes,[0 deg2rad(Angle)],[0 Value]);
        end
        
        function UpdateTimeQuality(comp,IsTimeOk)
            if IsTimeOk==true
                comp.TimeQualityLamp.Enable="off";
            else
                 comp.TimeQualityLamp.Enable="on";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.PolarAxes = polaraxes(comp.PlotAndControls);
            comp.PolarAxes.Layout.Row = 1;
            comp.PolarAxes.Layout.Column = 1;
            %polarplot(comp.PolarAxes,[0 0],[0 10]);
            comp.TimeQualityLamp.Enable="on";
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.PlotAndControls = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.PlotAndControls);
            comp.TimeQualityLabel = uilabel(comp.GridLayout2);
            comp.TimeQualityLabel.Text = {'Time'; ' Quality'};
            comp.TimeQualityLamp = uilamp(comp.GridLayout2);
            comp.TimeQualityLamp.Tooltip = {'Informuje o jakości czasu'; 'Gdy lapka świeci czas jest niezgodny z zegarem'};
            comp.WybierzLabel = uilabel(comp.GridLayout2);
            comp.WybierzLabel.Text = 'Wybierz ';
            comp.SelectPlot = uibutton(comp.GridLayout2, 'state');
            comp.SelectPlot.Tooltip = {'Wybierz wykres do widoku szczegółowego'};
            comp.SelectPlot.Text = '';
            comp.ZaleneodLabel = uilabel(comp.GridLayout2);
            comp.ZaleneodLabel.Text = {'Zależne'; 'od'};
            comp.ZaleneodDropDown = uidropdown(comp.GridLayout2);
            comp.ZaleneodDropDown.Items = {'Brak', 'Poprawna'};
            comp.ZaleneodDropDown.Value = 'Brak';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end