classdef UstawieniaDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ZmieButton                      matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        WybierzkolorButton              matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        KolorwykreswRGBLabel            matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        IlowykreswnastroneSpinner       matlab.ui.control.Spinner
        IlowykreswnastroneSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
        end

        % Value changed function: IlowykreswnastroneSpinner
        function IlowykreswnastroneSpinnerValueChanged(app, event)
            value = app.IlowykreswnastroneSpinner.Value;
            app.MainApp.NumberOfPlotsPerPage=value;
        end

        % Button pushed function: WybierzkolorButton
        function WybierzkolorButtonPushed(app, event)
            color = uisetcolor(app.MainApp.ColorOfPlots,"Wybierz kolor");
            app.Lamp.Color=color;
            app.MainApp.ColorOfPlots=color;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.UpdateOptions();
            delete(app)
        end

        % Button pushed function: ZmieButton
        function ZmieButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IlowykreswnastroneSpinnerLabel = uilabel(app.GridLayout2);
            app.IlowykreswnastroneSpinnerLabel.Text = {'Ilość wykresów na strone'; ''};
            app.IlowykreswnastroneSpinner = uispinner(app.GridLayout2);
            app.IlowykreswnastroneSpinner.Limits = [4 16];
            app.IlowykreswnastroneSpinner.ValueChangedFcn = createCallbackFcn(app, @IlowykreswnastroneSpinnerValueChanged, true);
            app.IlowykreswnastroneSpinner.Value = 4;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.KolorwykreswRGBLabel = uilabel(app.GridLayout3);
            app.KolorwykreswRGBLabel.Text = 'Kolor wykresów';
            app.Lamp = uilamp(app.GridLayout3);
            app.WybierzkolorButton = uibutton(app.GridLayout3, 'push');
            app.WybierzkolorButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzkolorButtonPushed, true);
            app.WybierzkolorButton.Text = {'Wybierz kolor'; ''};
            app.ZmieButton = uibutton(app.GridLayout, 'push');
            app.ZmieButton.ButtonPushedFcn = createCallbackFcn(app, @ZmieButtonPushed, true);
            app.ZmieButton.Text = 'Zmień';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UstawieniaDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end