classdef DeviceDetailsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        RamkazdanymiTab           matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        FRACSEC                   matlab.ui.control.Label
        MTQ_L_S_INDICATOR         matlab.ui.control.Label
        MTQ_L_S_PENDING           matlab.ui.control.Label
        MTQ_L_S_OCCURRED          matlab.ui.control.Label
        MTQ_L_S_DIRECTION         matlab.ui.control.Label
        FRACSECLabel              matlab.ui.control.Label
        MTQ_L_S_PENDINGLabel      matlab.ui.control.Label
        MTQ_L_S_INDICATORLabel    matlab.ui.control.Label
        MTQ_L_S_OCCURREDLabel     matlab.ui.control.Label
        MTQ_L_S_DIRECTIONLabel    matlab.ui.control.Label
        SOC                       matlab.ui.control.Label
        ID_CODE_SOURCE            matlab.ui.control.Label
        FRAMESIZE                 matlab.ui.control.Label
        SOCLabel                  matlab.ui.control.Label
        ID_CODE_SOURCELabel       matlab.ui.control.Label
        FRAMESIZELabel            matlab.ui.control.Label
        SYNCVERSION               matlab.ui.control.Label
        SYNCVERSIONLabel          matlab.ui.control.Label
        DIGITAL                   matlab.ui.control.Label
        ANALOG                    matlab.ui.control.Label
        DFREQ                     matlab.ui.control.Label
        FREQ                      matlab.ui.control.Label
        PHASORS1                  matlab.ui.control.Label
        PHASORS0                  matlab.ui.control.Label
        STAT_TRIGGER_REASON       matlab.ui.control.Label
        STAT_UNLOCKED_TIME        matlab.ui.control.Label
        STAT_PMU_TQ               matlab.ui.control.Label
        STAT_DATA_MODIFIED        matlab.ui.control.Label
        STAT_CNF_CHANGE           matlab.ui.control.Label
        STAT_PMU_TRIGGER          matlab.ui.control.Label
        STAT_DATA_SORTING         matlab.ui.control.Label
        STAT_PMU_SYNC             matlab.ui.control.Label
        STAT_DATA_ERROR           matlab.ui.control.Label
        DIGITALLabel              matlab.ui.control.Label
        ANALOGLabel               matlab.ui.control.Label
        DFREQLabel                matlab.ui.control.Label
        FREQLabel                 matlab.ui.control.Label
        PHASORS1Label             matlab.ui.control.Label
        PHASORS0Label             matlab.ui.control.Label
        STAT_TRIGGER_REASONLabel  matlab.ui.control.Label
        STAT_UNLOCKED_TIMELabel   matlab.ui.control.Label
        STAT_PMU_TQLabel          matlab.ui.control.Label
        STAT_DATA_MODIFIEDLabel   matlab.ui.control.Label
        STAT_CNF_CHANGELabel      matlab.ui.control.Label
        STAT_PMU_TRIGGERLabel     matlab.ui.control.Label
        STAT_DATA_SORTINGLabel    matlab.ui.control.Label
        STAT_PMU_SYNCLabel        matlab.ui.control.Label
        STAT_DATA_ERRORLabel      matlab.ui.control.Label
        Ramkazkonfiguracja12Tab   matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        ANUNIT_CNF                matlab.ui.control.Label
        ANUNIT_TYPE_CNF           matlab.ui.control.Label
        PHUNIT_CNF                matlab.ui.control.Label
        PHUNIT_TYPE_CNF           matlab.ui.control.Label
        DATA_RATE_CNF             matlab.ui.control.Label
        CFGCNT_CNF                matlab.ui.control.Label
        FNOM_CNF                  matlab.ui.control.Label
        DIGUNIT1_CNF              matlab.ui.control.Label
        DIGUNIT0_CNF              matlab.ui.control.Label
        CHNAM_DGNMR_CNF           matlab.ui.control.Label
        CHNAM_ANNMR_CNF           matlab.ui.control.Label
        CHNAM_PHNMR_CNF           matlab.ui.control.Label
        DGNMR_CNF                 matlab.ui.control.Label
        ANNMR_CNF                 matlab.ui.control.Label
        PHNMR_CNF                 matlab.ui.control.Label
        FORMAT_FORM_CNF           matlab.ui.control.Label
        FORMAT_PHASORS_CNF        matlab.ui.control.Label
        FORMAT_ANALOG_CNF         matlab.ui.control.Label
        FORMAT_FREQ_CNF           matlab.ui.control.Label
        ID_CODE_DATA_CNF          matlab.ui.control.Label
        STN_CNF                   matlab.ui.control.Label
        NUM_PMU_CNF               matlab.ui.control.Label
        TIME_BASE_CNF             matlab.ui.control.Label
        TIME_BASE_FLAGS_CNF       matlab.ui.control.Label
        FRACSEC_CNF               matlab.ui.control.Label
        MTQ_L_S_INDICATOR_CNF     matlab.ui.control.Label
        MTQ_L_S_PENDING_CNF       matlab.ui.control.Label
        MTQ_L_S_OCCURRED_CNF      matlab.ui.control.Label
        MTQ_L_S_DIRECTION_CNF     matlab.ui.control.Label
        SOC_CNF                   matlab.ui.control.Label
        ID_CODE_SOURCE_CNF        matlab.ui.control.Label
        FRAMESIZE_CNF             matlab.ui.control.Label
        SYNCVERSION_CNF           matlab.ui.control.Label
        ANUNITLabel               matlab.ui.control.Label
        ANUNIT_TYPELabel          matlab.ui.control.Label
        PHUNITLabel               matlab.ui.control.Label
        PHUNIT_TYPELabel          matlab.ui.control.Label
        DATA_RATELabel            matlab.ui.control.Label
        CFGCNTLabel               matlab.ui.control.Label
        FNOMLabel                 matlab.ui.control.Label
        DIGUNIT1Label             matlab.ui.control.Label
        DIGUNIT0Label             matlab.ui.control.Label
        CHNAM_DGNMRLabel          matlab.ui.control.Label
        CHNAM_ANNMRLabel          matlab.ui.control.Label
        CHNAM_PHNMRLabel          matlab.ui.control.Label
        DGNMRLabel                matlab.ui.control.Label
        ANNMRLabel                matlab.ui.control.Label
        PHNMRLabel                matlab.ui.control.Label
        FORMAT_FORMLabel          matlab.ui.control.Label
        FORMAT_ANALOGLabel        matlab.ui.control.Label
        FORMAT_PHASORSLabel       matlab.ui.control.Label
        FORMAT_FREQLabel          matlab.ui.control.Label
        ID_CODE_DATALabel         matlab.ui.control.Label
        STNLabel                  matlab.ui.control.Label
        NUM_PMULabel              matlab.ui.control.Label
        TIME_BASELabel            matlab.ui.control.Label
        TIME_BASE_FLAGSLabel      matlab.ui.control.Label
        FRACSECLabel_2            matlab.ui.control.Label
        MTQ_L_S_INDICATORLabel_2  matlab.ui.control.Label
        MTQ_L_S_PENDINGLabel_2    matlab.ui.control.Label
        MTQ_L_S_OCCURREDLabel_2   matlab.ui.control.Label
        MTQ_L_S_DIRECTIONLabel_2  matlab.ui.control.Label
        SOCLabel_2                matlab.ui.control.Label
        ID_CODE_SOURCELabel_2     matlab.ui.control.Label
        FRAMESIZELabel_2          matlab.ui.control.Label
        SYNCVERSIONLabel_2        matlab.ui.control.Label
        Ramkazkonfiguracja3Tab    matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        GRP_DLY_CNF3              matlab.ui.control.Label
        WINDOW_CNF3               matlab.ui.control.Label
        SVC_CLASS_CNF3            matlab.ui.control.Label
        PMU_ELEV_CNF3             matlab.ui.control.Label
        PMU_LON_CNF3              matlab.ui.control.Label
        PMU_LAT_CNF3              matlab.ui.control.Label
        ANSCALE_B_CNF3            matlab.ui.control.Label
        ANSCALE_M_CNF3            matlab.ui.control.Label
        PHSCALE_3_CNF3            matlab.ui.control.Label
        PHSCALE_2_CNF3            matlab.ui.control.Label
        PHSCALE_1_24_31_CNF3      matlab.ui.control.Label
        PHSCALE_1_16_18_CNF3      matlab.ui.control.Label
        PHSCALE_1_19_CNF3         matlab.ui.control.Label
        PHSCALE_1_20_23_CNF3      matlab.ui.control.Label
        PHSCALE_1_15_CNF3         matlab.ui.control.Label
        PHSCALE_1_11_14_CNF3      matlab.ui.control.Label
        PHSCALE_1_10_CNF3         matlab.ui.control.Label
        PHSCALE_1_9_CNF3          matlab.ui.control.Label
        PHSCALE_1_8_CNF3          matlab.ui.control.Label
        PHSCALE_1_7_CNF3          matlab.ui.control.Label
        PHSCALE_1_5_CFN3          matlab.ui.control.Label
        PHSCALE_1_6_CNF3          matlab.ui.control.Label
        PHSCALE_1_4_CNF3          matlab.ui.control.Label
        PHSCALE_1_3_CNF3          matlab.ui.control.Label
        PHSCALE_1_2_CNF3          matlab.ui.control.Label
        PHSCALE_1_1_CNF3          matlab.ui.control.Label
        G_PMU_ID_CNF3             matlab.ui.control.Label
        PHSCALE_1_0_CNF3          matlab.ui.control.Label
        CONT_IDX_CNF3             matlab.ui.control.Label
        CFGCNT_CNF3               matlab.ui.control.Label
        DATA_RATE_CNF3            matlab.ui.control.Label
        FNOM_CNF3                 matlab.ui.control.Label
        DIGUNIT1_CNF3             matlab.ui.control.Label
        DIGUNIT0_CNF3             matlab.ui.control.Label
        CHNAM_DGNMR_CNF3          matlab.ui.control.Label
        CHNAM_ANNMR_CNF3          matlab.ui.control.Label
        CHNAM_PHNMR_CNF3          matlab.ui.control.Label
        DGNMR_CNF3                matlab.ui.control.Label
        ANNMR_CNF3                matlab.ui.control.Label
        PHNMR_CNF3                matlab.ui.control.Label
        FORMAT_FORM_CNF3          matlab.ui.control.Label
        FORMAT_PHASORS_CNF3       matlab.ui.control.Label
        FORMAT_ANALOG_CNF3        matlab.ui.control.Label
        FORMAT_FREQ_CNF3          matlab.ui.control.Label
        ID_CODE_DATA_CNF3         matlab.ui.control.Label
        STN_CNF3                  matlab.ui.control.Label
        NUM_PMU_CNF3              matlab.ui.control.Label
        TIME_BASE_CNF3            matlab.ui.control.Label
        TIME_BASE_FLAGS_CNF3      matlab.ui.control.Label
        FRACSEC_CNF3              matlab.ui.control.Label
        MTQ_L_S_INDICATOR_CNF3    matlab.ui.control.Label
        MTQ_L_S_PENDING_CNF3      matlab.ui.control.Label
        MTQ_L_S_OCCURRED_CNF3     matlab.ui.control.Label
        SOC_CNF3                  matlab.ui.control.Label
        ID_CODE_SOURCE_CNF3       matlab.ui.control.Label
        FRAMESIZE_CNF3            matlab.ui.control.Label
        SYNCVERSION_CNF3          matlab.ui.control.Label
        GRP_DLYLabel              matlab.ui.control.Label
        WINDOWLabel               matlab.ui.control.Label
        SVC_CLASSLabel            matlab.ui.control.Label
        PMU_ELEVLabel             matlab.ui.control.Label
        PMU_LONLabel              matlab.ui.control.Label
        PMU_LATLabel              matlab.ui.control.Label
        ANSCALE_BLabel            matlab.ui.control.Label
        ANSCALE_MLabel            matlab.ui.control.Label
        PHSCALE_3Label            matlab.ui.control.Label
        PHSCALE_2Label            matlab.ui.control.Label
        PHSCALE_1_24_31Label      matlab.ui.control.Label
        PHSCALE_1_16_18Label      matlab.ui.control.Label
        PHSCALE_1_19Label         matlab.ui.control.Label
        PHSCALE_1_20_23Label      matlab.ui.control.Label
        PHSCALE_1_15Label         matlab.ui.control.Label
        PHSCALE_1_11_14Label      matlab.ui.control.Label
        PHSCALE_1_10Label         matlab.ui.control.Label
        PHSCALE_1_9Label          matlab.ui.control.Label
        PHSCALE_1_8Label          matlab.ui.control.Label
        PHSCALE_1_7Label          matlab.ui.control.Label
        PHSCALE_1_6Label          matlab.ui.control.Label
        PHSCALE_1_5Label          matlab.ui.control.Label
        PHSCALE_1_4Label          matlab.ui.control.Label
        PHSCALE_1_3Label          matlab.ui.control.Label
        PHSCALE_1_2Label          matlab.ui.control.Label
        PHSCALE_1_1Label          matlab.ui.control.Label
        PHSCALE_1_0Label          matlab.ui.control.Label
        G_PMU_IDLabel             matlab.ui.control.Label
        CONT_IDXLabel_2           matlab.ui.control.Label
        DATA_RATELabel_2          matlab.ui.control.Label
        CFGCNTLabel_2             matlab.ui.control.Label
        FNOMLabel_2               matlab.ui.control.Label
        DIGUNIT1Label_2           matlab.ui.control.Label
        DIGUNIT0Label_2           matlab.ui.control.Label
        CHNAM_DGNMRLabel_2        matlab.ui.control.Label
        CHNAM_ANNMRLabel_2        matlab.ui.control.Label
        CHNAM_PHNMRLabel_2        matlab.ui.control.Label
        DGNMRLabel_2              matlab.ui.control.Label
        ANNMRLabel_2              matlab.ui.control.Label
        PHNMRLabel_2              matlab.ui.control.Label
        FORMAT_FORMLabel_2        matlab.ui.control.Label
        FORMAT_PHASORSLabel_2     matlab.ui.control.Label
        FORMAT_ANALOGLabel_2      matlab.ui.control.Label
        FORMAT_FREQLabel_2        matlab.ui.control.Label
        ID_CODE_DATALabel_2       matlab.ui.control.Label
        STNLabel_2                matlab.ui.control.Label
        NUM_PMULabel_2            matlab.ui.control.Label
        TIME_BASELabel_2          matlab.ui.control.Label
        TIME_BASE_FLAGSLabel_2    matlab.ui.control.Label
        FRACSECLabel_3            matlab.ui.control.Label
        MTQ_L_S_INDICATORLabel_3  matlab.ui.control.Label
        MTQ_L_S_PENDINGLabel_3    matlab.ui.control.Label
        MTQ_L_S_OCCURREDLabel_3   matlab.ui.control.Label
        MTQ_L_S_DIRECTIONLabel_3  matlab.ui.control.Label
        MTQ_L_S_DIRECTION_CNF3    matlab.ui.control.Label
        SOCLabel_3                matlab.ui.control.Label
        ID_CODE_SOURCELabel_3     matlab.ui.control.Label
        FRAMESIZELabel_3          matlab.ui.control.Label
        SYNCVERSIONLabel_3        matlab.ui.control.Label
    end

    
    properties (Access = private)
        index % Description
    end
    
    properties (Access = public)
        ID % Description
        STN
    end
    
    methods (Access = public)
        
        function UpdateValues(app,pmu)
            arguments
                app DeviceDetailsDialog
                pmu
            end
            pmu=pmu(uint32(app.ID));
            app.index=0;
            if pmu.cnf_version~=0
                if pmu.cnf_version==1 || pmu.cnf_version==2
                    [n,~]=size(pmu.cnf.STN);
                    for i=1:n
                        if strcmp(strip(replace(string(pmu.cnf.STN(i,:)),char(0x00),' ')),app.STN)
                            app.index=i;
                            break;
                        end
                    end
                elseif pmu.cnf_version==3
                    [~,n]=size(pmu.cnf3.STN);
                    for i=1:n
                        if strcmp(strip(replace(string(pmu.cnf3.STN(i,:)),char(0x00),' ')),app.STN)
                            app.index=i;
                            break;
                        end
                    end
                end
            end
            if app.index~=0
                if ~isempty(pmu.data)
                    if ~isempty(pmu.data.SYNCVERSION)
                    app.SYNCVERSION.Text=string(pmu.data.SYNCVERSION);
                    end
                    if ~isempty(pmu.data.FRAMESIZE)
                    app.FRAMESIZE.Text=string(pmu.data.FRAMESIZE);
                    end
                    if ~isempty(pmu.data.ID_CODE_SOURCE)
                    app.ID_CODE_SOURCE.Text=string(pmu.data.ID_CODE_SOURCE);
                    end
                    if ~isempty(pmu.data.SOC)
                    app.SOC.Text=string(pmu.data.SOC);
                    end
                    if ~isempty(pmu.data.MTQ_L_S_DIRECTION)
                    app.MTQ_L_S_DIRECTION.Text=string(pmu.data.MTQ_L_S_DIRECTION);
                    end
                    if ~isempty(pmu.data.MTQ_L_S_OCCURRED)
                    app.MTQ_L_S_OCCURRED.Text=string(pmu.data.MTQ_L_S_OCCURRED);
                    end
                    if ~isempty(pmu.data.MTQ_L_S_PENDING)
                    app.MTQ_L_S_PENDING.Text=string(pmu.data.MTQ_L_S_PENDING);
                    end
                    if ~isempty(pmu.data.MTQ_L_S_INDICATOR)
                    app.MTQ_L_S_INDICATOR.Text=string(pmu.data.MTQ_L_S_INDICATOR);
                    end
                    if ~isempty(pmu.data.STAT_DATA_ERROR)
                        app.STAT_DATA_ERROR.Text=string(pmu.data.STAT_DATA_ERROR(app.STN));
                    end
                    if ~isempty(pmu.data.STAT_PMU_SYNC)
                        app.STAT_PMU_SYNC.Text=string(pmu.data.STAT_PMU_SYNC(app.STN));
                    end
                    if ~isempty(pmu.data.STAT_DATA_SORTING)
                        app.STAT_DATA_SORTING.Text=string(pmu.data.STAT_DATA_SORTING(app.STN));
                    end
                    if ~isempty(pmu.data.STAT_PMU_TRIGGER)
                        app.STAT_PMU_TRIGGER.Text=string(pmu.data.STAT_PMU_TRIGGER(app.STN));
                    end
                    if ~isempty(pmu.data.STAT_CNF_CHANGE)
                        app.STAT_CNF_CHANGE.Text=string(pmu.data.STAT_CNF_CHANGE(app.STN));
                    end
                    if ~isempty(pmu.data.STAT_DATA_MODIFIED)
                        app.STAT_DATA_MODIFIED.Text=string(pmu.data.STAT_DATA_MODIFIED(app.STN));
                    end
                    if ~isempty(pmu.data.STAT_PMU_TQ)
                        app.STAT_PMU_TQ.Text=string(pmu.data.STAT_PMU_TQ(app.STN));
                    end
                    if ~isempty(pmu.data.STAT_UNLOCKED_TIME)
                    app.STAT_UNLOCKED_TIME.Text=string(pmu.data.STAT_UNLOCKED_TIME(app.STN));
                    end
                    if ~isempty(pmu.data.STAT_TRIGGER_REASON)
                        app.STAT_TRIGGER_REASON.Text=string(pmu.data.STAT_TRIGGER_REASON(app.STN));
                    end
                    if ~isempty(pmu.data.PHASORS0)
                        handleToMap=pmu.data.PHASORS0(app.STN);
                        text="";
                        k=keys(handleToMap);
                        [n,m]=size(k);
                        for i=1:m
                            key=char(cell2mat(k(i)));
                            text=text+" "+string(handleToMap(key));
                        end
                        app.PHASORS0.Text=text;
                    end
                    if ~isempty(pmu.data.PHASORS1)
                        handleToMap=pmu.data.PHASORS1(app.STN);
                        text="";
                        k=keys(handleToMap);
                        [n,m]=size(k);
                        for i=1:m
                            key=char(cell2mat(k(i)));
                            text=text+" "+string(handleToMap(key));
                        end
                        app.PHASORS1.Text=text;
                    end
                    if ~isempty(pmu.data.FREQ)
                        app.FREQ.Text=string(pmu.data.FREQ(app.STN));
                    end
                    if ~isempty(pmu.data.DFREQ)
                        app.DFREQ.Text=string(pmu.data.DFREQ(app.STN));
                    end
                    if ~isempty(pmu.data.ANALOG)
                        handleToMap=pmu.data.ANALOG(app.STN);
                        text="";
                        k=keys(handleToMap);
                        [n,m]=size(k);
                        for i=1:m
                            key=char(cell2mat(k(i)));
                            text=text+" "+string(handleToMap(key));
                        end
                        app.ANALOG.Text=text;
                    end
                    if ~isempty(pmu.data.DIGITAL)
                        handleToMap=pmu.data.DIGITAL(app.STN);
                        text="";
                        k=keys(handleToMap);
                        [n,m]=size(k);
                        for i=1:m
                            key=char(cell2mat(k(i)));
                            text=text+" "+string(handleToMap(key));
                        end
                        app.DIGITAL.Text=text;
                    end
                    if ~isempty(pmu.data.FRACSEC)
                    app.FRACSEC.Text=string(pmu.data.FRACSEC);
                    end
    
                end
                if ~isempty(pmu.cnf3)
                    
                end
                if ~isempty(pmu.cnf)
                    if ~isempty(pmu.cnf.SYNCVERSION)
                        app.SYNCVERSION_CNF.Text=string(pmu.cnf.SYNCVERSION);
                    end
                    if ~isempty(pmu.cnf.FRAMESIZE)
                        app.FRAMESIZE_CNF.Text=string(pmu.cnf.FRAMESIZE);
                    end
                    if ~isempty(pmu.cnf.ID_CODE_SOURCE)
                        app.ID_CODE_SOURCE_CNF.Text=string(pmu.cnf.ID_CODE_SOURCE);
                    end
                    if ~isempty(pmu.cnf.SOC)
                        app.SOC_CNF.Text=string(pmu.cnf.SOC);
                    end
                    if ~isempty(pmu.cnf.MTQ_L_S_DIRECTION)
                    app.MTQ_L_S_DIRECTION_CNF.Text=string(pmu.cnf.MTQ_L_S_DIRECTION);
                    end
                    if ~isempty(pmu.cnf.MTQ_L_S_OCCURRED)
                    app.MTQ_L_S_OCCURRED_CNF.Text=string(pmu.cnf.MTQ_L_S_OCCURRED);
                    end
                    if ~isempty(pmu.cnf.MTQ_L_S_PENDING)
                    app.MTQ_L_S_PENDING_CNF.Text=string(pmu.cnf.MTQ_L_S_PENDING);
                    end
                    if ~isempty(pmu.cnf.MTQ_L_S_INDICATOR)
                    app.MTQ_L_S_INDICATOR_CNF.Text=string(pmu.cnf.MTQ_L_S_INDICATOR);
                    end
                    if ~isempty(pmu.cnf.FRACSEC)
                        app.FRACSEC_CNF.Text=app.GetText(pmu.cnf.FRACSEC);
                    end
                    if ~isempty(pmu.cnf.TIME_BASE_FLAGS)
                        app.TIME_BASE_FLAGS_CNF.Text=string(pmu.cnf.TIME_BASE_FLAGS);
                    end
                    if ~isempty(pmu.cnf.TIME_BASE)
                        app.TIME_BASE_CNF.Text=string(pmu.cnf.TIME_BASE);
                    end
                    if ~isempty(pmu.cnf.NUM_PMU)
                        app.NUM_PMU_CNF.Text=string(pmu.cnf.NUM_PMU);
                    end
                    if ~isempty(pmu.cnf.STN)
                        text="";
                        text=text+" "+strip(replace(string(pmu.cnf.STN(app.index,:)),char(0x00),' '));
                        app.STN_CNF.Text=text;
                    end
                    if ~isempty(pmu.cnf.ID_CODE_DATA)
                        app.ID_CODE_DATA_CNF.Text=app.GetText(pmu.cnf.ID_CODE_DATA);
                    end
                    if ~isempty(pmu.cnf.FORMAT_FREQ)
                        app.FORMAT_FREQ_CNF.Text=app.GetText(pmu.cnf.FORMAT_FREQ);
                    end
                    if ~isempty(pmu.cnf.FORMAT_ANALOG)
                        app.FORMAT_ANALOG_CNF.Text=app.GetText(pmu.cnf.FORMAT_ANALOG);
                    end
                    if ~isempty(pmu.cnf.FORMAT_PHASORS)
                    app.FORMAT_PHASORS_CNF.Text=app.GetText(pmu.cnf.FORMAT_PHASORS);
                    end
                    if ~isempty(pmu.cnf.FORMAT_FORM)
                    app.FORMAT_FORM_CNF.Text=app.GetText(pmu.cnf.FORMAT_FORM);
                    end
                    if ~isempty(pmu.cnf.PHNMR)
                        app.PHNMR_CNF.Text=app.GetText(pmu.cnf.PHNMR);
                    end
                    if ~isempty(pmu.cnf.ANNMR)
                        app.ANNMR_CNF.Text=app.GetText(pmu.cnf.ANNMR);
                    end
                    if ~isempty(pmu.cnf.DGNMR)
                        app.DGNMR_CNF.Text=app.GetText(pmu.cnf.DGNMR);
                    end
                    if ~isempty(pmu.cnf.CHNAM_PHNMR)
                        app.CHNAM_PHNMR_CNF.Text=app.GetText(pmu.cnf.CHNAM_PHNMR);
                    end
                    if ~isempty(pmu.cnf.CHNAM_ANNMR)
                        app.CHNAM_ANNMR_CNF.Text=app.GetText(pmu.cnf.CHNAM_ANNMR);
                    end
                    if ~isempty(pmu.cnf.CHNAM_DGNMR)
                        app.CHNAM_DGNMR_CNF.Text=app.GetText(pmu.cnf.CHNAM_DGNMR);
                    end
                    if ~isempty(pmu.cnf.DIGUNIT0)
                    app.DIGUNIT0_CNF.Text=app.GetText(pmu.cnf.DIGUNIT0);
                    end
                    if ~isempty(pmu.cnf.DIGUNIT1)
                    app.DIGUNIT1_CNF.Text=app.GetText(pmu.cnf.DIGUNIT1);
                    end
                    if ~isempty(pmu.cnf.FNOM)
                    app.FNOM_CNF.Text=app.GetText(pmu.cnf.FNOM);
                    end
                    if ~isempty(pmu.cnf.CFGCNT)
                    app.CFGCNT_CNF.Text=app.GetText(pmu.cnf.CFGCNT);
                    end
                    if ~isempty(pmu.cnf.DATA_RATE)
                    app.DATA_RATE_CNF.Text=app.GetText(pmu.cnf.DATA_RATE);
                    end
                    if ~isempty(pmu.cnf.PHUNIT_TYPE)
                    app.PHUNIT_TYPE_CNF.Text=app.GetText(pmu.cnf.PHUNIT_TYPE);
                    end
                    if ~isempty(pmu.cnf.PHUNIT)
                    app.PHUNIT_CNF.Text=app.GetText(pmu.cnf.PHUNIT);
                    end
                    if ~isempty(pmu.cnf.ANUNIT_TYPE)
                    app.ANUNIT_TYPE_CNF.Text=app.GetText(pmu.cnf.ANUNIT_TYPE);
                    end
                    if ~isempty(pmu.cnf.ANUNIT)
                    app.ANUNIT_CNF.Text=app.GetText(pmu.cnf.ANUNIT);
                    end
                end
            end
    
        end

        function text=GetText(app,array)
            text="";
            [~,j]=size(array);
            for n=1:j
                text=text+" "+strip(replace(string(array(app.index,n)),char(0x00),' '));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ID, STN)
            arguments
                app DeviceDetailsDialog
                ID
                STN
            end
            app.ID=ID;
            app.STN=STN;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RamkazdanymiTab = uitab(app.TabGroup);
            app.RamkazdanymiTab.Title = 'Ramka z danymi';
            app.GridLayout2 = uigridlayout(app.RamkazdanymiTab);
            app.STAT_DATA_ERRORLabel = uilabel(app.GridLayout2);
            app.STAT_DATA_ERRORLabel.Text = 'STAT_DATA_ERROR:';
            app.STAT_PMU_SYNCLabel = uilabel(app.GridLayout2);
            app.STAT_PMU_SYNCLabel.Text = 'STAT_PMU_SYNC:';
            app.STAT_DATA_SORTINGLabel = uilabel(app.GridLayout2);
            app.STAT_DATA_SORTINGLabel.Text = 'STAT_DATA_SORTING:';
            app.STAT_PMU_TRIGGERLabel = uilabel(app.GridLayout2);
            app.STAT_PMU_TRIGGERLabel.Text = 'STAT_PMU_TRIGGER:';
            app.STAT_CNF_CHANGELabel = uilabel(app.GridLayout2);
            app.STAT_CNF_CHANGELabel.Text = 'STAT_CNF_CHANGE:';
            app.STAT_DATA_MODIFIEDLabel = uilabel(app.GridLayout2);
            app.STAT_DATA_MODIFIEDLabel.Text = 'STAT_DATA_MODIFIED:';
            app.STAT_PMU_TQLabel = uilabel(app.GridLayout2);
            app.STAT_PMU_TQLabel.Text = 'STAT_PMU_TQ:';
            app.STAT_UNLOCKED_TIMELabel = uilabel(app.GridLayout2);
            app.STAT_UNLOCKED_TIMELabel.Text = 'STAT_UNLOCKED_TIME:';
            app.STAT_TRIGGER_REASONLabel = uilabel(app.GridLayout2);
            app.STAT_TRIGGER_REASONLabel.Text = 'STAT_TRIGGER_REASON:';
            app.PHASORS0Label = uilabel(app.GridLayout2);
            app.PHASORS0Label.Text = 'PHASORS0:';
            app.PHASORS1Label = uilabel(app.GridLayout2);
            app.PHASORS1Label.Text = 'PHASORS1:';
            app.FREQLabel = uilabel(app.GridLayout2);
            app.FREQLabel.Text = 'FREQ:';
            app.DFREQLabel = uilabel(app.GridLayout2);
            app.DFREQLabel.Text = 'DFREQ:';
            app.ANALOGLabel = uilabel(app.GridLayout2);
            app.ANALOGLabel.Text = 'ANALOG:';
            app.DIGITALLabel = uilabel(app.GridLayout2);
            app.DIGITALLabel.Text = 'DIGITAL:';
            app.STAT_DATA_ERROR = uilabel(app.GridLayout2);
            app.STAT_DATA_ERROR.Tooltip = {'0 - good measurement data, no errors                                                  '; '1 - PMU error. No information about data                                                   '; '2 - PMU in test mode (do not use values) or                                                   absent data tags have been inserted (do not use values)                                                            '; '3 - PMU error (do not use values)'};
            app.STAT_DATA_ERROR.Text = '';
            app.STAT_PMU_SYNC = uilabel(app.GridLayout2);
            app.STAT_PMU_SYNC.Tooltip = {'PMU sync, 0 when in sync with a UTC traceable time source'};
            app.STAT_PMU_SYNC.Text = '';
            app.STAT_DATA_SORTING = uilabel(app.GridLayout2);
            app.STAT_DATA_SORTING.Tooltip = {'Data sorting, 0 by time stamp, 1 by arrival'};
            app.STAT_DATA_SORTING.Text = '';
            app.STAT_PMU_TRIGGER = uilabel(app.GridLayout2);
            app.STAT_PMU_TRIGGER.Tooltip = {'PMU trigger detected, 0 when no trigger'};
            app.STAT_PMU_TRIGGER.Text = '';
            app.STAT_CNF_CHANGE = uilabel(app.GridLayout2);
            app.STAT_CNF_CHANGE.Tooltip = {'Configuration change, set to 1 for 1 min to advise configuration will change, and clear to 0 when change effected.'};
            app.STAT_CNF_CHANGE.Text = '';
            app.STAT_DATA_MODIFIED = uilabel(app.GridLayout2);
            app.STAT_DATA_MODIFIED.Tooltip = {'Data modified, 1 if data modified by post processing, 0 otherwise'};
            app.STAT_DATA_MODIFIED.Text = '';
            app.STAT_PMU_TQ = uilabel(app.GridLayout2);
            app.STAT_PMU_TQ.Tooltip = {'PMU Time Quality'; '7 - Estimated maximum time error > 10 ms or time error unknown'; '6 - Estimated maximum time error < 10 ms'; '5 - Estimated maximum time error < 1 ms'; '4 - Estimated maximum time error < 100 μs'; '3 - Estimated maximum time error < 10 μs'; '2 - Estimated maximum time error < 1 μs'; '1 - Estimated maximum time error < 100 ns'; '0 - Not used (indicates code from previous version of profile)'};
            app.STAT_PMU_TQ.Text = '';
            app.STAT_UNLOCKED_TIME = uilabel(app.GridLayout2);
            app.STAT_UNLOCKED_TIME.Tooltip = {'Unlocked time: '; '0 - sync locked or unlocked < 10 s (best quality)'; '1 - 10 s ≤ unlocked time < 100 s'; '2 - 100 s < unlock time ≤ 1000 s'; '3 - unlocked time > 1000 s'};
            app.STAT_UNLOCKED_TIME.Text = '';
            app.STAT_TRIGGER_REASON = uilabel(app.GridLayout2);
            app.STAT_TRIGGER_REASON.Tooltip = {'Trigger reason:'; '15–8: Available for user definition   '; '7 - Digital '; '6 - Reserved'; '5 - df/dt High '; '4 - Frequency high or low'; '3 - Phase angle diff '; '2 - Magnitude high'; '1 - Magnitude low '; '0 - Manual'};
            app.STAT_TRIGGER_REASON.Text = '';
            app.PHASORS0 = uilabel(app.GridLayout2);
            app.PHASORS0.Tooltip = {'magnitude/real'};
            app.PHASORS0.Text = '';
            app.PHASORS1 = uilabel(app.GridLayout2);
            app.PHASORS1.Tooltip = {'angle/imaginary'};
            app.PHASORS1.Text = '';
            app.FREQ = uilabel(app.GridLayout2);
            app.FREQ.Tooltip = {'Frequency deviation from nominal, in mHz'};
            app.FREQ.Text = '';
            app.DFREQ = uilabel(app.GridLayout2);
            app.DFREQ.Tooltip = {'ROCOF, in hertz per second times 100'};
            app.DFREQ.Text = '';
            app.ANALOG = uilabel(app.GridLayout2);
            app.ANALOG.Tooltip = {'Analog word. 16-bit integer. It could be sampled data such as control signal or transducer value. Values and ranges defined by user.'};
            app.ANALOG.Text = '';
            app.DIGITAL = uilabel(app.GridLayout2);
            app.DIGITAL.Tooltip = {'Digital status word. It could be bit mapped status or flag. Values and ranges defined by user.'};
            app.DIGITAL.Text = '';
            app.SYNCVERSIONLabel = uilabel(app.GridLayout2);
            app.SYNCVERSIONLabel.Text = 'SYNCVERSION:';
            app.SYNCVERSION = uilabel(app.GridLayout2);
            app.SYNCVERSION.Tooltip = {'Version number (1–15)'; 'Version 1 (1) for messages defined in IEEE Std C37.118-2005 [B6].'; 'Version 2 (2) for messages added in this revision, IEEE Std C37.118.2-2011.'};
            app.SYNCVERSION.Text = '';
            app.FRAMESIZELabel = uilabel(app.GridLayout2);
            app.FRAMESIZELabel.Text = 'FRAMESIZE:';
            app.ID_CODE_SOURCELabel = uilabel(app.GridLayout2);
            app.ID_CODE_SOURCELabel.Text = 'ID_CODE_SOURCE:';
            app.SOCLabel = uilabel(app.GridLayout2);
            app.SOCLabel.Text = 'SOC:';
            app.FRAMESIZE = uilabel(app.GridLayout2);
            app.FRAMESIZE.Tooltip = {'Total number of bytes in the frame, including CHK. Range = maximum 65535'};
            app.FRAMESIZE.Text = '';
            app.ID_CODE_SOURCE = uilabel(app.GridLayout2);
            app.ID_CODE_SOURCE.Tooltip = {'Data stream ID number, assigned by user, 1–65534'};
            app.ID_CODE_SOURCE.Text = '';
            app.SOC = uilabel(app.GridLayout2);
            app.SOC.Tooltip = {'Time stamp, SOC count starting at midnight 01-Jan-1970 (UNIX time base). Range is 136 years, rolls over 2106 AD. Leap seconds are not included in count, so each year has the same number of seconds except leap years, which have an extra day (86 400 s)'};
            app.SOC.Text = '';
            app.MTQ_L_S_DIRECTIONLabel = uilabel(app.GridLayout2);
            app.MTQ_L_S_DIRECTIONLabel.Text = 'MTQ_L_S_DIRECTION:';
            app.MTQ_L_S_OCCURREDLabel = uilabel(app.GridLayout2);
            app.MTQ_L_S_OCCURREDLabel.Text = 'MTQ_L_S_OCCURRED:';
            app.MTQ_L_S_INDICATORLabel = uilabel(app.GridLayout2);
            app.MTQ_L_S_INDICATORLabel.Text = 'MTQ_L_S_INDICATOR';
            app.MTQ_L_S_PENDINGLabel = uilabel(app.GridLayout2);
            app.MTQ_L_S_PENDINGLabel.Text = 'MTQ_L_S_PENDING';
            app.FRACSECLabel = uilabel(app.GridLayout2);
            app.FRACSECLabel.Text = 'FRACSEC:';
            app.MTQ_L_S_DIRECTION = uilabel(app.GridLayout2);
            app.MTQ_L_S_DIRECTION.Tooltip = {'Leap Second Direction―0 for add, 1 for delete'};
            app.MTQ_L_S_DIRECTION.Text = '';
            app.MTQ_L_S_OCCURRED = uilabel(app.GridLayout2);
            app.MTQ_L_S_OCCURRED.Tooltip = {'Leap Second Occurred―set in the first second after the leap second occurs and remains set for 24 h'};
            app.MTQ_L_S_OCCURRED.Text = '';
            app.MTQ_L_S_PENDING = uilabel(app.GridLayout2);
            app.MTQ_L_S_PENDING.Tooltip = {'Leap Second Pending―shall be set not more than 60 s nor less than 1 s before a leap second occurs, and cleared in the second after the leap second occurs'};
            app.MTQ_L_S_PENDING.Text = '';
            app.MTQ_L_S_INDICATOR = uilabel(app.GridLayout2);
            app.MTQ_L_S_INDICATOR.Tooltip = {'Message Time Quality indicator code:'; '15 - Fault clock failure, time not reliable'; '11 - Time within 10 s of UTC'; '10 - Time within 1 s of UTC'; '9 - Time within 10–1s of UTC'; '8 - Time within 10–2s of UTC'; '7 - Time within 10–3s of UTC'; '6 - Time within 10–4s of UTC'; '5 - Time within 10–5s of UTC'; '4 - Time within 10–6s of UTC'; '3 - Time within 10–7s of UTC'; '2 - Time within 10–8s of UTC'; '1 - Time within 10–9s of UTC'; '0 - Normal operation, clock locked to UTC traceable source'};
            app.MTQ_L_S_INDICATOR.Text = '';
            app.FRACSEC = uilabel(app.GridLayout2);
            app.FRACSEC.Tooltip = {'When divided by TIME_BASE yields the actual fractional second. FRACSEC used in all messages to and from a given PMU shall use the same TIME_BASE that is provided in the configuration message from that PMU'};
            app.FRACSEC.Text = '';
            app.Ramkazkonfiguracja12Tab = uitab(app.TabGroup);
            app.Ramkazkonfiguracja12Tab.Title = 'Ramka z konfiguracja 1&2';
            app.GridLayout3 = uigridlayout(app.Ramkazkonfiguracja12Tab);
            app.SYNCVERSIONLabel_2 = uilabel(app.GridLayout3);
            app.SYNCVERSIONLabel_2.Text = 'SYNCVERSION:';
            app.FRAMESIZELabel_2 = uilabel(app.GridLayout3);
            app.FRAMESIZELabel_2.Text = 'FRAMESIZE:';
            app.ID_CODE_SOURCELabel_2 = uilabel(app.GridLayout3);
            app.ID_CODE_SOURCELabel_2.Text = 'ID_CODE_SOURCE:';
            app.SOCLabel_2 = uilabel(app.GridLayout3);
            app.SOCLabel_2.Text = 'SOC:';
            app.MTQ_L_S_DIRECTIONLabel_2 = uilabel(app.GridLayout3);
            app.MTQ_L_S_DIRECTIONLabel_2.Text = 'MTQ_L_S_DIRECTION:';
            app.MTQ_L_S_OCCURREDLabel_2 = uilabel(app.GridLayout3);
            app.MTQ_L_S_OCCURREDLabel_2.Text = 'MTQ_L_S_OCCURRED:';
            app.MTQ_L_S_PENDINGLabel_2 = uilabel(app.GridLayout3);
            app.MTQ_L_S_PENDINGLabel_2.Text = 'MTQ_L_S_PENDING:';
            app.MTQ_L_S_INDICATORLabel_2 = uilabel(app.GridLayout3);
            app.MTQ_L_S_INDICATORLabel_2.Text = 'MTQ_L_S_INDICATOR:';
            app.FRACSECLabel_2 = uilabel(app.GridLayout3);
            app.FRACSECLabel_2.Text = 'FRACSEC:';
            app.TIME_BASE_FLAGSLabel = uilabel(app.GridLayout3);
            app.TIME_BASE_FLAGSLabel.Text = 'TIME_BASE_FLAGS:';
            app.TIME_BASELabel = uilabel(app.GridLayout3);
            app.TIME_BASELabel.Text = 'TIME_BASE:';
            app.NUM_PMULabel = uilabel(app.GridLayout3);
            app.NUM_PMULabel.Text = 'NUM_PMU:';
            app.STNLabel = uilabel(app.GridLayout3);
            app.STNLabel.Text = 'STN:';
            app.ID_CODE_DATALabel = uilabel(app.GridLayout3);
            app.ID_CODE_DATALabel.Text = 'ID_CODE_DATA:';
            app.FORMAT_FREQLabel = uilabel(app.GridLayout3);
            app.FORMAT_FREQLabel.Text = 'FORMAT_FREQ:';
            app.FORMAT_PHASORSLabel = uilabel(app.GridLayout3);
            app.FORMAT_PHASORSLabel.Text = 'FORMAT_PHASORS:';
            app.FORMAT_ANALOGLabel = uilabel(app.GridLayout3);
            app.FORMAT_ANALOGLabel.Text = 'FORMAT_ANALOG:';
            app.FORMAT_FORMLabel = uilabel(app.GridLayout3);
            app.FORMAT_FORMLabel.Text = 'FORMAT_FORM:';
            app.PHNMRLabel = uilabel(app.GridLayout3);
            app.PHNMRLabel.Text = 'PHNMR:';
            app.ANNMRLabel = uilabel(app.GridLayout3);
            app.ANNMRLabel.Text = 'ANNMR:';
            app.DGNMRLabel = uilabel(app.GridLayout3);
            app.DGNMRLabel.Text = 'DGNMR:';
            app.CHNAM_PHNMRLabel = uilabel(app.GridLayout3);
            app.CHNAM_PHNMRLabel.Text = 'CHNAM_PHNMR:';
            app.CHNAM_ANNMRLabel = uilabel(app.GridLayout3);
            app.CHNAM_ANNMRLabel.Text = 'CHNAM_ANNMR:';
            app.CHNAM_DGNMRLabel = uilabel(app.GridLayout3);
            app.CHNAM_DGNMRLabel.Text = 'CHNAM_DGNMR:';
            app.DIGUNIT0Label = uilabel(app.GridLayout3);
            app.DIGUNIT0Label.Text = 'DIGUNIT0:';
            app.DIGUNIT1Label = uilabel(app.GridLayout3);
            app.DIGUNIT1Label.Text = 'DIGUNIT1:';
            app.FNOMLabel = uilabel(app.GridLayout3);
            app.FNOMLabel.Text = 'FNOM:';
            app.CFGCNTLabel = uilabel(app.GridLayout3);
            app.CFGCNTLabel.Text = 'CFGCNT:';
            app.DATA_RATELabel = uilabel(app.GridLayout3);
            app.DATA_RATELabel.Text = 'DATA_RATE:';
            app.PHUNIT_TYPELabel = uilabel(app.GridLayout3);
            app.PHUNIT_TYPELabel.Text = 'PHUNIT_TYPE:';
            app.PHUNITLabel = uilabel(app.GridLayout3);
            app.PHUNITLabel.Text = 'PHUNIT:';
            app.ANUNIT_TYPELabel = uilabel(app.GridLayout3);
            app.ANUNIT_TYPELabel.Text = 'ANUNIT_TYPE:';
            app.ANUNITLabel = uilabel(app.GridLayout3);
            app.ANUNITLabel.Text = 'ANUNIT:';
            app.SYNCVERSION_CNF = uilabel(app.GridLayout3);
            app.SYNCVERSION_CNF.Tooltip = {'Version number (1–15)'; 'Version 1 (1) for messages defined in IEEE Std C37.118-2005 [B6].'; 'Version 2 (2) for messages added in this revision, IEEE Std C37.118.2-2011.'};
            app.SYNCVERSION_CNF.Text = '';
            app.FRAMESIZE_CNF = uilabel(app.GridLayout3);
            app.FRAMESIZE_CNF.Tooltip = {'Total number of bytes in the frame, including CHK. Range = maximum 65535'};
            app.FRAMESIZE_CNF.Text = '';
            app.ID_CODE_SOURCE_CNF = uilabel(app.GridLayout3);
            app.ID_CODE_SOURCE_CNF.Tooltip = {'Data stream ID number, assigned by user, 1–65534'};
            app.ID_CODE_SOURCE_CNF.Text = '';
            app.SOC_CNF = uilabel(app.GridLayout3);
            app.SOC_CNF.Tooltip = {'Time stamp, SOC count starting at midnight 01-Jan-1970 (UNIX time base). Range is 136 years, rolls over 2106 AD. Leap seconds are not included in count, so each year has the same number of seconds except leap years, which have an extra day (86 400 s)'};
            app.SOC_CNF.Text = '';
            app.MTQ_L_S_DIRECTION_CNF = uilabel(app.GridLayout3);
            app.MTQ_L_S_DIRECTION_CNF.Tooltip = {'Leap Second Direction―0 for add, 1 for delete'};
            app.MTQ_L_S_DIRECTION_CNF.Text = '';
            app.MTQ_L_S_OCCURRED_CNF = uilabel(app.GridLayout3);
            app.MTQ_L_S_OCCURRED_CNF.Tooltip = {'Leap Second Occurred―set in the first second after the leap second occurs and remains set for 24 h'};
            app.MTQ_L_S_OCCURRED_CNF.Text = '';
            app.MTQ_L_S_PENDING_CNF = uilabel(app.GridLayout3);
            app.MTQ_L_S_PENDING_CNF.Tooltip = {'Leap Second Pending―shall be set not more than 60 s nor less than 1 s before a leap second occurs, and cleared in the second after the leap second occurs'};
            app.MTQ_L_S_PENDING_CNF.Text = '';
            app.MTQ_L_S_INDICATOR_CNF = uilabel(app.GridLayout3);
            app.MTQ_L_S_INDICATOR_CNF.Tooltip = {'Message Time Quality indicator code:'; '15 - Fault clock failure, time not reliable'; '11 - Time within 10 s of UTC'; '10 - Time within 1 s of UTC'; '9 - Time within 10–1s of UTC'; '8 - Time within 10–2s of UTC'; '7 - Time within 10–3s of UTC'; '6 - Time within 10–4s of UTC'; '5 - Time within 10–5s of UTC'; '4 - Time within 10–6s of UTC'; '3 - Time within 10–7s of UTC'; '2 - Time within 10–8s of UTC'; '1 - Time within 10–9s of UTC'; '0 - Normal operation, clock locked to UTC traceable source'};
            app.MTQ_L_S_INDICATOR_CNF.Text = '';
            app.FRACSEC_CNF = uilabel(app.GridLayout3);
            app.FRACSEC_CNF.Tooltip = {'When divided by TIME_BASE yields the actual fractional second. FRACSEC used in all messages to and from a given PMU shall use the same TIME_BASE that is provided in the configuration message from that PMU'};
            app.FRACSEC_CNF.Text = '';
            app.TIME_BASE_FLAGS_CNF = uilabel(app.GridLayout3);
            app.TIME_BASE_FLAGS_CNF.Tooltip = {'Reserved for flags (high 8 bits).'};
            app.TIME_BASE_FLAGS_CNF.Text = '';
            app.TIME_BASE_CNF = uilabel(app.GridLayout3);
            app.TIME_BASE_CNF.Tooltip = {'24-bit unsigned integer, which is the subdivision of the second that the'; 'FRACSEC is based on'};
            app.TIME_BASE_CNF.Text = '';
            app.NUM_PMU_CNF = uilabel(app.GridLayout3);
            app.NUM_PMU_CNF.Tooltip = {'The number of PMUs included in the data frame. No limit specified. The actual limit'; 'will be determined by the limit of 65 535 bytes in one frame (“FRAMESIZE” field).'};
            app.NUM_PMU_CNF.Text = '';
            app.STN_CNF = uilabel(app.GridLayout3);
            app.STN_CNF.Tooltip = {'Station Name―16 bytes in ASCII format.'};
            app.STN_CNF.Text = '';
            app.ID_CODE_DATA_CNF = uilabel(app.GridLayout3);
            app.ID_CODE_DATA_CNF.Tooltip = {'Data stream ID number, 16-bit integer, defined in 6.2. It identifies the data stream in'; 'field 3 and the data source in fields 9 and higher. Field 3 identifies the stream that is'; 'being received. The IDCODEs in field 9 (and higher if more than one PMU data is'; 'present) identify the original source of the data and will usually be associated with a'; 'particular PMU. The IDCODEs in a data stream received directly from a PMU will'; 'usually be the same.'};
            app.ID_CODE_DATA_CNF.Text = '';
            app.FORMAT_FREQ_CNF = uilabel(app.GridLayout3);
            app.FORMAT_FREQ_CNF.Tooltip = {'0 - FREQ/DFREQ 16-bit integer'; '1 - floating point'};
            app.FORMAT_FREQ_CNF.Text = '';
            app.FORMAT_ANALOG_CNF = uilabel(app.GridLayout3);
            app.FORMAT_ANALOG_CNF.Tooltip = {'0 - analogs 16-bit integer '; '1 - floating point'};
            app.FORMAT_ANALOG_CNF.Text = '';
            app.FORMAT_PHASORS_CNF = uilabel(app.GridLayout3);
            app.FORMAT_PHASORS_CNF.Tooltip = {'0 - phasors 16-bit integer'; '1 - floating point'};
            app.FORMAT_PHASORS_CNF.Text = '';
            app.FORMAT_FORM_CNF = uilabel(app.GridLayout3);
            app.FORMAT_FORM_CNF.Tooltip = {'0 - phasor real and imaginary (rectangular)'; '1 - magnitude and angle (polar)'};
            app.FORMAT_FORM_CNF.Text = '';
            app.PHNMR_CNF = uilabel(app.GridLayout3);
            app.PHNMR_CNF.Tooltip = {'Number of phasors―2-byte integer.'};
            app.PHNMR_CNF.Text = '';
            app.ANNMR_CNF = uilabel(app.GridLayout3);
            app.ANNMR_CNF.Tooltip = {'Number of analog values―2-byte integer.'};
            app.ANNMR_CNF.Text = '';
            app.DGNMR_CNF = uilabel(app.GridLayout3);
            app.DGNMR_CNF.Tooltip = {'Number of digital status words―2-byte integer.'};
            app.DGNMR_CNF.Text = '';
            app.CHNAM_PHNMR_CNF = uilabel(app.GridLayout3);
            app.CHNAM_PHNMR_CNF.Tooltip = {'Phasors channel names'};
            app.CHNAM_PHNMR_CNF.Text = '';
            app.CHNAM_ANNMR_CNF = uilabel(app.GridLayout3);
            app.CHNAM_ANNMR_CNF.Tooltip = {'Analogs channel names'};
            app.CHNAM_ANNMR_CNF.Text = '';
            app.CHNAM_DGNMR_CNF = uilabel(app.GridLayout3);
            app.CHNAM_DGNMR_CNF.Tooltip = {'Digitals channel names'};
            app.CHNAM_DGNMR_CNF.Text = '';
            app.DIGUNIT0_CNF = uilabel(app.GridLayout3);
            app.DIGUNIT0_CNF.Tooltip = {'Mask words for digital status words.'};
            app.DIGUNIT0_CNF.Text = '';
            app.DIGUNIT1_CNF = uilabel(app.GridLayout3);
            app.DIGUNIT1_CNF.Tooltip = {'Mask words for digital status words.'};
            app.DIGUNIT1_CNF.Text = '';
            app.FNOM_CNF = uilabel(app.GridLayout3);
            app.FNOM_CNF.Tooltip = {'Nominal line frequency Bits 15–1: Reserved Bit 0: 1―Fundamental frequency = 50 Hz 0―Fundamental frequency = 60 Hz'};
            app.FNOM_CNF.Text = '';
            app.CFGCNT_CNF = uilabel(app.GridLayout3);
            app.CFGCNT_CNF.Tooltip = {'Configuration change count is incremented each time a change is made in the PMU'};
            app.CFGCNT_CNF.Text = '';
            app.DATA_RATE_CNF = uilabel(app.GridLayout3);
            app.DATA_RATE_CNF.Tooltip = {'Rate of phasor data transmissions―2-byte integer word (–32 767 to +32 767)    '};
            app.DATA_RATE_CNF.Text = '';
            app.PHUNIT_TYPE_CNF = uilabel(app.GridLayout3);
            app.PHUNIT_TYPE_CNF.Tooltip = {'Conversion factor for phasor channels. Four bytes for each phasor.'; '0―voltage; 1―current'};
            app.PHUNIT_TYPE_CNF.Text = '';
            app.PHUNIT_CNF = uilabel(app.GridLayout3);
            app.PHUNIT_CNF.Tooltip = {'An unsigned 24-bit word in 10–5 V or amperes per bit to scale'; '16-bit integer data (if transmitted data is in floating-point format, this 24-bit value shall'; 'be ignored).'};
            app.PHUNIT_CNF.Text = '';
            app.ANUNIT_TYPE_CNF = uilabel(app.GridLayout3);
            app.ANUNIT_TYPE_CNF.Tooltip = {'Conversion factor for analog channels. Four bytes for each analog value.'; 'Most significant byte: 0―single point-on-wave, 1―rms of analog input,'; '2―peak of analog input, 5–64―reserved for future definition; 65–255―user definable'};
            app.ANUNIT_TYPE_CNF.Text = '';
            app.ANUNIT_CNF = uilabel(app.GridLayout3);
            app.ANUNIT_CNF.Tooltip = {'A signed 24-bit word, user defined scaling.'};
            app.ANUNIT_CNF.Text = '';
            app.Ramkazkonfiguracja3Tab = uitab(app.TabGroup);
            app.Ramkazkonfiguracja3Tab.Title = 'Ramka z konfiguracja 3';
            app.GridLayout4 = uigridlayout(app.Ramkazkonfiguracja3Tab);
            app.SYNCVERSIONLabel_3 = uilabel(app.GridLayout4);
            app.SYNCVERSIONLabel_3.Text = 'SYNCVERSION:';
            app.FRAMESIZELabel_3 = uilabel(app.GridLayout4);
            app.FRAMESIZELabel_3.Text = 'FRAMESIZE:';
            app.ID_CODE_SOURCELabel_3 = uilabel(app.GridLayout4);
            app.ID_CODE_SOURCELabel_3.Text = 'ID_CODE_SOURCE:';
            app.SOCLabel_3 = uilabel(app.GridLayout4);
            app.SOCLabel_3.Text = 'SOC:';
            app.MTQ_L_S_DIRECTION_CNF3 = uilabel(app.GridLayout4);
            app.MTQ_L_S_DIRECTION_CNF3.Tooltip = {'Leap Second Direction―0 for add, 1 for delete'};
            app.MTQ_L_S_DIRECTION_CNF3.Text = '';
            app.MTQ_L_S_DIRECTIONLabel_3 = uilabel(app.GridLayout4);
            app.MTQ_L_S_DIRECTIONLabel_3.Text = 'MTQ_L_S_DIRECTION:';
            app.MTQ_L_S_OCCURREDLabel_3 = uilabel(app.GridLayout4);
            app.MTQ_L_S_OCCURREDLabel_3.Text = 'MTQ_L_S_OCCURRED:';
            app.MTQ_L_S_PENDINGLabel_3 = uilabel(app.GridLayout4);
            app.MTQ_L_S_PENDINGLabel_3.Text = 'MTQ_L_S_PENDING:';
            app.MTQ_L_S_INDICATORLabel_3 = uilabel(app.GridLayout4);
            app.MTQ_L_S_INDICATORLabel_3.Text = 'MTQ_L_S_INDICATOR:';
            app.FRACSECLabel_3 = uilabel(app.GridLayout4);
            app.FRACSECLabel_3.Text = 'FRACSEC:';
            app.TIME_BASE_FLAGSLabel_2 = uilabel(app.GridLayout4);
            app.TIME_BASE_FLAGSLabel_2.Text = 'TIME_BASE_FLAGS:';
            app.TIME_BASELabel_2 = uilabel(app.GridLayout4);
            app.TIME_BASELabel_2.Text = 'TIME_BASE:';
            app.NUM_PMULabel_2 = uilabel(app.GridLayout4);
            app.NUM_PMULabel_2.Text = 'NUM_PMU:';
            app.STNLabel_2 = uilabel(app.GridLayout4);
            app.STNLabel_2.Text = 'STN:';
            app.ID_CODE_DATALabel_2 = uilabel(app.GridLayout4);
            app.ID_CODE_DATALabel_2.Text = 'ID_CODE_DATA:';
            app.FORMAT_FREQLabel_2 = uilabel(app.GridLayout4);
            app.FORMAT_FREQLabel_2.Text = 'FORMAT_FREQ:';
            app.FORMAT_ANALOGLabel_2 = uilabel(app.GridLayout4);
            app.FORMAT_ANALOGLabel_2.Text = 'FORMAT_ANALOG:';
            app.FORMAT_PHASORSLabel_2 = uilabel(app.GridLayout4);
            app.FORMAT_PHASORSLabel_2.Text = 'FORMAT_PHASORS:';
            app.FORMAT_FORMLabel_2 = uilabel(app.GridLayout4);
            app.FORMAT_FORMLabel_2.Text = 'FORMAT_FORM:';
            app.PHNMRLabel_2 = uilabel(app.GridLayout4);
            app.PHNMRLabel_2.Text = 'PHNMR:';
            app.ANNMRLabel_2 = uilabel(app.GridLayout4);
            app.ANNMRLabel_2.Text = 'ANNMR:';
            app.DGNMRLabel_2 = uilabel(app.GridLayout4);
            app.DGNMRLabel_2.Text = 'DGNMR:';
            app.CHNAM_PHNMRLabel_2 = uilabel(app.GridLayout4);
            app.CHNAM_PHNMRLabel_2.Text = 'CHNAM_PHNMR:';
            app.CHNAM_ANNMRLabel_2 = uilabel(app.GridLayout4);
            app.CHNAM_ANNMRLabel_2.Text = 'CHNAM_ANNMR:';
            app.CHNAM_DGNMRLabel_2 = uilabel(app.GridLayout4);
            app.CHNAM_DGNMRLabel_2.Text = 'CHNAM_DGNMR:';
            app.DIGUNIT0Label_2 = uilabel(app.GridLayout4);
            app.DIGUNIT0Label_2.Text = 'DIGUNIT0:';
            app.DIGUNIT1Label_2 = uilabel(app.GridLayout4);
            app.DIGUNIT1Label_2.Text = 'DIGUNIT1:';
            app.FNOMLabel_2 = uilabel(app.GridLayout4);
            app.FNOMLabel_2.Text = 'FNOM:';
            app.CFGCNTLabel_2 = uilabel(app.GridLayout4);
            app.CFGCNTLabel_2.Text = 'CFGCNT:';
            app.DATA_RATELabel_2 = uilabel(app.GridLayout4);
            app.DATA_RATELabel_2.Text = 'DATA_RATE:';
            app.CONT_IDXLabel_2 = uilabel(app.GridLayout4);
            app.CONT_IDXLabel_2.Text = 'CONT_IDX:';
            app.G_PMU_IDLabel = uilabel(app.GridLayout4);
            app.G_PMU_IDLabel.Text = 'G_PMU_ID:';
            app.PHSCALE_1_0Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_0Label.Text = 'PHSCALE_1_0:';
            app.PHSCALE_1_1Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_1Label.Text = 'PHSCALE_1_1:';
            app.PHSCALE_1_2Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_2Label.Text = 'PHSCALE_1_2:';
            app.PHSCALE_1_3Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_3Label.Text = 'PHSCALE_1_3:';
            app.PHSCALE_1_4Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_4Label.Text = 'PHSCALE_1_4:';
            app.PHSCALE_1_5Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_5Label.Text = 'PHSCALE_1_5:';
            app.PHSCALE_1_6Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_6Label.Text = 'PHSCALE_1_6:';
            app.PHSCALE_1_7Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_7Label.Text = 'PHSCALE_1_7:';
            app.PHSCALE_1_8Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_8Label.Text = 'PHSCALE_1_8:';
            app.PHSCALE_1_9Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_9Label.Text = 'PHSCALE_1_9:';
            app.PHSCALE_1_10Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_10Label.Text = 'PHSCALE_1_10:';
            app.PHSCALE_1_11_14Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_11_14Label.Text = 'PHSCALE_1_11_14:';
            app.PHSCALE_1_15Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_15Label.Text = 'PHSCALE_1_15:';
            app.PHSCALE_1_20_23Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_20_23Label.Text = 'PHSCALE_1_20_23:';
            app.PHSCALE_1_19Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_19Label.Text = 'PHSCALE_1_19:';
            app.PHSCALE_1_16_18Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_16_18Label.Text = 'PHSCALE_1_16_18:';
            app.PHSCALE_1_24_31Label = uilabel(app.GridLayout4);
            app.PHSCALE_1_24_31Label.Text = 'PHSCALE_1_24_31:';
            app.PHSCALE_2Label = uilabel(app.GridLayout4);
            app.PHSCALE_2Label.Text = 'PHSCALE_2:';
            app.PHSCALE_3Label = uilabel(app.GridLayout4);
            app.PHSCALE_3Label.Text = 'PHSCALE_3:';
            app.ANSCALE_MLabel = uilabel(app.GridLayout4);
            app.ANSCALE_MLabel.Text = 'ANSCALE_M:';
            app.ANSCALE_BLabel = uilabel(app.GridLayout4);
            app.ANSCALE_BLabel.Text = 'ANSCALE_B:';
            app.PMU_LATLabel = uilabel(app.GridLayout4);
            app.PMU_LATLabel.Text = 'PMU_LAT:';
            app.PMU_LONLabel = uilabel(app.GridLayout4);
            app.PMU_LONLabel.Text = 'PMU_LON:';
            app.PMU_ELEVLabel = uilabel(app.GridLayout4);
            app.PMU_ELEVLabel.Text = 'PMU_ELEV:';
            app.SVC_CLASSLabel = uilabel(app.GridLayout4);
            app.SVC_CLASSLabel.Text = 'SVC_CLASS:';
            app.WINDOWLabel = uilabel(app.GridLayout4);
            app.WINDOWLabel.Text = 'WINDOW:';
            app.GRP_DLYLabel = uilabel(app.GridLayout4);
            app.GRP_DLYLabel.Text = 'GRP_DLY:';
            app.SYNCVERSION_CNF3 = uilabel(app.GridLayout4);
            app.SYNCVERSION_CNF3.Tooltip = {'Version number (1–15)'; 'Version 1 (1) for messages defined in IEEE Std C37.118-2005 [B6].'; 'Version 2 (2) for messages added in this revision, IEEE Std C37.118.2-2011.'};
            app.SYNCVERSION_CNF3.Text = '';
            app.FRAMESIZE_CNF3 = uilabel(app.GridLayout4);
            app.FRAMESIZE_CNF3.Tooltip = {'Total number of bytes in the frame, including CHK. Range = maximum 65535'};
            app.FRAMESIZE_CNF3.Text = '';
            app.ID_CODE_SOURCE_CNF3 = uilabel(app.GridLayout4);
            app.ID_CODE_SOURCE_CNF3.Tooltip = {'Data stream ID number, assigned by user, 1–65534'};
            app.ID_CODE_SOURCE_CNF3.Text = '';
            app.SOC_CNF3 = uilabel(app.GridLayout4);
            app.SOC_CNF3.Tooltip = {'Time stamp, SOC count starting at midnight 01-Jan-1970 (UNIX time base). Range is 136 years, rolls over 2106 AD. Leap seconds are not included in count, so each year has the same number of seconds except leap years, which have an extra day (86 400 s)'};
            app.SOC_CNF3.Text = '';
            app.MTQ_L_S_OCCURRED_CNF3 = uilabel(app.GridLayout4);
            app.MTQ_L_S_OCCURRED_CNF3.Tooltip = {'Leap Second Occurred―set in the first second after the leap second occurs and remains set for 24 h'};
            app.MTQ_L_S_OCCURRED_CNF3.Text = '';
            app.MTQ_L_S_PENDING_CNF3 = uilabel(app.GridLayout4);
            app.MTQ_L_S_PENDING_CNF3.Tooltip = {'Leap Second Pending―shall be set not more than 60 s nor less than 1 s before a leap second occurs, and cleared in the second after the leap second occurs'};
            app.MTQ_L_S_PENDING_CNF3.Text = '';
            app.MTQ_L_S_INDICATOR_CNF3 = uilabel(app.GridLayout4);
            app.MTQ_L_S_INDICATOR_CNF3.Tooltip = {'Message Time Quality indicator code:'; '15 - Fault clock failure, time not reliable'; '11 - Time within 10 s of UTC'; '10 - Time within 1 s of UTC'; '9 - Time within 10–1s of UTC'; '8 - Time within 10–2s of UTC'; '7 - Time within 10–3s of UTC'; '6 - Time within 10–4s of UTC'; '5 - Time within 10–5s of UTC'; '4 - Time within 10–6s of UTC'; '3 - Time within 10–7s of UTC'; '2 - Time within 10–8s of UTC'; '1 - Time within 10–9s of UTC'; '0 - Normal operation, clock locked to UTC traceable source'};
            app.MTQ_L_S_INDICATOR_CNF3.Text = '';
            app.FRACSEC_CNF3 = uilabel(app.GridLayout4);
            app.FRACSEC_CNF3.Tooltip = {'When divided by TIME_BASE yields the actual fractional second. FRACSEC used in all messages to and from a given PMU shall use the same TIME_BASE that is provided in the configuration message from that PMU'};
            app.FRACSEC_CNF3.Text = '';
            app.TIME_BASE_FLAGS_CNF3 = uilabel(app.GridLayout4);
            app.TIME_BASE_FLAGS_CNF3.Tooltip = {'Reserved for flags (high 8 bits).'};
            app.TIME_BASE_FLAGS_CNF3.Text = '';
            app.TIME_BASE_CNF3 = uilabel(app.GridLayout4);
            app.TIME_BASE_CNF3.Tooltip = {'24-bit unsigned integer, which is the subdivision of the second that the'; 'FRACSEC is based on'};
            app.TIME_BASE_CNF3.Text = '';
            app.NUM_PMU_CNF3 = uilabel(app.GridLayout4);
            app.NUM_PMU_CNF3.Tooltip = {'The number of PMUs included in the data frame. No limit specified. The actual limit'; 'will be determined by the limit of 65 535 bytes in one frame (“FRAMESIZE” field).'};
            app.NUM_PMU_CNF3.Text = '';
            app.STN_CNF3 = uilabel(app.GridLayout4);
            app.STN_CNF3.Tooltip = {'Station Name―16 bytes in ASCII format.'};
            app.STN_CNF3.Text = '';
            app.ID_CODE_DATA_CNF3 = uilabel(app.GridLayout4);
            app.ID_CODE_DATA_CNF3.Tooltip = {'Data stream ID number, 16-bit integer, defined in 6.2. It identifies the data stream in'; 'field 3 and the data source in fields 9 and higher. Field 3 identifies the stream that is'; 'being received. The IDCODEs in field 9 (and higher if more than one PMU data is'; 'present) identify the original source of the data and will usually be associated with a'; 'particular PMU. The IDCODEs in a data stream received directly from a PMU will'; 'usually be the same.'};
            app.ID_CODE_DATA_CNF3.Text = '';
            app.FORMAT_FREQ_CNF3 = uilabel(app.GridLayout4);
            app.FORMAT_FREQ_CNF3.Tooltip = {'0 - FREQ/DFREQ 16-bit integer'; '1 - floating point'};
            app.FORMAT_FREQ_CNF3.Text = '';
            app.FORMAT_ANALOG_CNF3 = uilabel(app.GridLayout4);
            app.FORMAT_ANALOG_CNF3.Tooltip = {'0 - analogs 16-bit integer '; '1 - floating point'};
            app.FORMAT_ANALOG_CNF3.Text = '';
            app.FORMAT_PHASORS_CNF3 = uilabel(app.GridLayout4);
            app.FORMAT_PHASORS_CNF3.Tooltip = {'0 - phasors 16-bit integer'; '1 - floating point'};
            app.FORMAT_PHASORS_CNF3.Text = '';
            app.FORMAT_FORM_CNF3 = uilabel(app.GridLayout4);
            app.FORMAT_FORM_CNF3.Tooltip = {'0 - phasor real and imaginary (rectangular)'; '1 - magnitude and angle (polar)'};
            app.FORMAT_FORM_CNF3.Text = '';
            app.PHNMR_CNF3 = uilabel(app.GridLayout4);
            app.PHNMR_CNF3.Tooltip = {'Number of phasors―2-byte integer.'};
            app.PHNMR_CNF3.Text = '';
            app.ANNMR_CNF3 = uilabel(app.GridLayout4);
            app.ANNMR_CNF3.Tooltip = {'Number of analog values―2-byte integer.'};
            app.ANNMR_CNF3.Text = '';
            app.DGNMR_CNF3 = uilabel(app.GridLayout4);
            app.DGNMR_CNF3.Tooltip = {'Number of digital status words―2-byte integer.'};
            app.DGNMR_CNF3.Text = '';
            app.CHNAM_PHNMR_CNF3 = uilabel(app.GridLayout4);
            app.CHNAM_PHNMR_CNF3.Tooltip = {'Number of digital status words―2-byte integer.'};
            app.CHNAM_PHNMR_CNF3.Text = '';
            app.CHNAM_ANNMR_CNF3 = uilabel(app.GridLayout4);
            app.CHNAM_ANNMR_CNF3.Tooltip = {'Analogs channel names'};
            app.CHNAM_ANNMR_CNF3.Text = '';
            app.CHNAM_DGNMR_CNF3 = uilabel(app.GridLayout4);
            app.CHNAM_DGNMR_CNF3.Tooltip = {'Analogs channel names'};
            app.CHNAM_DGNMR_CNF3.Text = '';
            app.DIGUNIT0_CNF3 = uilabel(app.GridLayout4);
            app.DIGUNIT0_CNF3.Tooltip = {'Mask words for digital status words.'};
            app.DIGUNIT0_CNF3.Text = '';
            app.DIGUNIT1_CNF3 = uilabel(app.GridLayout4);
            app.DIGUNIT1_CNF3.Tooltip = {'Mask words for digital status words.'};
            app.DIGUNIT1_CNF3.Text = '';
            app.FNOM_CNF3 = uilabel(app.GridLayout4);
            app.FNOM_CNF3.Tooltip = {'Nominal line frequency Bits 15–1: Reserved Bit 0: 1―Fundamental frequency = 50 Hz 0―Fundamental frequency = 60 Hz'};
            app.FNOM_CNF3.Text = '';
            app.DATA_RATE_CNF3 = uilabel(app.GridLayout4);
            app.DATA_RATE_CNF3.Tooltip = {'Rate of phasor data transmissions―2-byte integer word (–32 767 to +32 767)    '};
            app.DATA_RATE_CNF3.Text = '';
            app.CFGCNT_CNF3 = uilabel(app.GridLayout4);
            app.CFGCNT_CNF3.Tooltip = {'Configuration change count is incremented each time a change is made in the PMU'};
            app.CFGCNT_CNF3.Text = '';
            app.CONT_IDX_CNF3 = uilabel(app.GridLayout4);
            app.CONT_IDX_CNF3.Tooltip = {'Continuation index for fragmented frames:'; '0: only frame in configuration, no further frames'; '1: first frame in series, more to follow'; '2–65534: number of each succeeding frame, in order'; '65535 (hex FFFF): last frame in series'};
            app.CONT_IDX_CNF3.Text = '';
            app.PHSCALE_1_0_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_0_CNF3.Tooltip = {'Not used, reserved'};
            app.PHSCALE_1_0_CNF3.Text = '';
            app.G_PMU_ID_CNF3 = uilabel(app.GridLayout4);
            app.G_PMU_ID_CNF3.Tooltip = {'This 128-bit Global PMU ID shall be a user-assigned value. It shall be stored in the'; 'PMU or other sending device so it can be sent with this configuration 3 message. It'; 'allows uniquely identifying PMUs in a system that has more than 65535 PMUs. The'; 'coding for the 16 bytes is left to the user for assignment'};
            app.G_PMU_ID_CNF3.Text = '';
            app.PHSCALE_1_1_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_1_CNF3.Tooltip = {'Up sampled with interpolation'};
            app.PHSCALE_1_1_CNF3.Text = '';
            app.PHSCALE_1_2_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_2_CNF3.Tooltip = {'Upsampled with extrapolation'};
            app.PHSCALE_1_2_CNF3.Text = '';
            app.PHSCALE_1_3_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_3_CNF3.Tooltip = {'Down sampled by reselection (selecting every Nth sample)'};
            app.PHSCALE_1_3_CNF3.Text = '';
            app.PHSCALE_1_4_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_4_CNF3.Tooltip = {'Down sampled with FIR filter'};
            app.PHSCALE_1_4_CNF3.Text = '';
            app.PHSCALE_1_6_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_6_CNF3.Tooltip = {'Filtered without changing sampling'};
            app.PHSCALE_1_6_CNF3.Text = '';
            app.PHSCALE_1_5_CFN3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_5_CFN3.Tooltip = {'Down sampled with non-FIR filter'};
            app.PHSCALE_1_5_CFN3.Text = '';
            app.PHSCALE_1_7_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_7_CNF3.Tooltip = {'Phasor magnitude adjusted for calibration'};
            app.PHSCALE_1_7_CNF3.Text = '';
            app.PHSCALE_1_8_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_8_CNF3.Tooltip = {'Phasor phase adjusted for calibration'};
            app.PHSCALE_1_8_CNF3.Text = '';
            app.PHSCALE_1_9_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_9_CNF3.Tooltip = {'Phasor phase adjusted for rotation ( ±30º, ±120º, etc.)'};
            app.PHSCALE_1_9_CNF3.Text = '';
            app.PHSCALE_1_10_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_10_CNF3.Tooltip = {'Pseudo-phasor value (combined from other phasors)'};
            app.PHSCALE_1_10_CNF3.Text = '';
            app.PHSCALE_1_11_14_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_11_14_CNF3.Tooltip = {'Reserved for future assignment'};
            app.PHSCALE_1_11_14_CNF3.Text = '';
            app.PHSCALE_1_15_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_15_CNF3.Tooltip = {'Modification applied, type not here defined'};
            app.PHSCALE_1_15_CNF3.Text = '';
            app.PHSCALE_1_20_23_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_20_23_CNF3.Tooltip = {'Reserved for future use'};
            app.PHSCALE_1_20_23_CNF3.Text = '';
            app.PHSCALE_1_19_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_19_CNF3.Tooltip = {'0―voltage; 1―current.'};
            app.PHSCALE_1_19_CNF3.Text = '';
            app.PHSCALE_1_16_18_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_16_18_CNF3.Tooltip = {'Phasor component, coded as follows'; '7: Reserved'; '6: Phase C'; '5: Phase B'; '4: Phase A'; '3: Reserved'; '2: Negative sequence'; '1: Positive sequence'; '0: Zero sequence'};
            app.PHSCALE_1_16_18_CNF3.Text = '';
            app.PHSCALE_1_24_31_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_1_24_31_CNF3.Tooltip = {'Available for user designation'};
            app.PHSCALE_1_24_31_CNF3.Text = '';
            app.PHSCALE_2_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_2_CNF3.Tooltip = {'The second 4-byte word is the scale factor Y in 32-bit IEEE floating point. This scales'; 'phasor data to primary volts or amperes. If phasors are transmitted in floating-point'; 'format and scaled already, this value shall be set to 1'};
            app.PHSCALE_2_CNF3.Text = '';
            app.PHSCALE_3_CNF3 = uilabel(app.GridLayout4);
            app.PHSCALE_3_CNF3.Tooltip = {'The third 4-byte word is the phasor angle adjustment θ in radians represented in'; '32-bit IEEE floating point. If phasors are transmitted in floating-point format and'; 'scaled already, this value shall be set to 0.'};
            app.PHSCALE_3_CNF3.Text = '';
            app.ANSCALE_M_CNF3 = uilabel(app.GridLayout4);
            app.ANSCALE_M_CNF3.Tooltip = {'Linear scaling for analog channels. For analog value X, this defines scale M and offset'; 'B for X'' = M × X + B.'; 'First 4 bytes: Magnitude scaling M in 32-bit floating point.'; 'Last 4 bytes: Offset B in 32-bit floating point.'};
            app.ANSCALE_M_CNF3.Text = '';
            app.ANSCALE_B_CNF3 = uilabel(app.GridLayout4);
            app.ANSCALE_B_CNF3.Tooltip = {'Linear scaling for analog channels. For analog value X, this defines scale M and offset'; 'B for X'' = M × X + B.'; 'First 4 bytes: Magnitude scaling M in 32-bit floating point.'; 'Last 4 bytes: Offset B in 32-bit floating point.'};
            app.ANSCALE_B_CNF3.Text = '';
            app.PMU_LAT_CNF3 = uilabel(app.GridLayout4);
            app.PMU_LAT_CNF3.Tooltip = {'PMU Latitude in degrees, range –90.0 to +90.0. Positive values are N of equator.'; 'WGS 84 datum. Number in 32-bit IEEE floating-point format. For unspecified'; 'locations, infinity shall be used.'};
            app.PMU_LAT_CNF3.Text = '';
            app.PMU_LON_CNF3 = uilabel(app.GridLayout4);
            app.PMU_LON_CNF3.Tooltip = {'PMU Longitude in degrees, range –179.99999999 to +180. Positive values are E of'; 'the prime meridian. WGS 84 datum. Number in 32-bit IEEE floating-point format.'; 'For unspecified locations, infinity shall be used'};
            app.PMU_LON_CNF3.Text = '';
            app.PMU_ELEV_CNF3 = uilabel(app.GridLayout4);
            app.PMU_ELEV_CNF3.Tooltip = {'PMU Elevation in meters, Positive values are above mean sea level. WGS 84 datum.'; 'Number in 32-bit IEEE floating-point format. For unspecified locations, infinity shall'; 'be used.'};
            app.PMU_ELEV_CNF3.Text = '';
            app.SVC_CLASS_CNF3 = uilabel(app.GridLayout4);
            app.SVC_CLASS_CNF3.Tooltip = {'Service class, as defined in IEEE Std C37.118.1, a single ASCII character. In 2011 it'; 'is M or P.'};
            app.SVC_CLASS_CNF3.Text = '';
            app.WINDOW_CNF3 = uilabel(app.GridLayout4);
            app.WINDOW_CNF3.Tooltip = {'Phasor measurement window length including all filters and estimation windows in'; 'effect. Value is in microseconds, 4-byte signed integer value (to nearest'; 'microsecond). (For information only, any required compensation is already applied to'; 'the measurement.)'};
            app.WINDOW_CNF3.Text = '';
            app.GRP_DLY_CNF3 = uilabel(app.GridLayout4);
            app.GRP_DLY_CNF3.Tooltip = {'Phasor measurement group delay including all filters and estimation windows in'; 'effect. Value is in microseconds, 4-byte signed integer value (to nearest'; 'microsecond). (For information only, any required compensation is already applied to'; 'the measurement.)'};
            app.GRP_DLY_CNF3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeviceDetailsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end