classdef DodajUrzadzenieDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout5           matlab.ui.container.GridLayout
        IdEditField           matlab.ui.control.NumericEditField
        IdEditFieldLabel      matlab.ui.control.Label
        DodajButton           matlab.ui.control.Button
        WysyaniePanel         matlab.ui.container.Panel
        GridLayout4           matlab.ui.container.GridLayout
        IpEditField_2         matlab.ui.control.EditField
        IpEditField_2Label    matlab.ui.control.Label
        PortEditField_2       matlab.ui.control.NumericEditField
        PortEditField_2Label  matlab.ui.control.Label
        OdbirPanel            matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        IpEditField           matlab.ui.control.EditField
        IpEditFieldLabel      matlab.ui.control.Label
        PortEditField         matlab.ui.control.NumericEditField
        PortEditFieldLabel    matlab.ui.control.Label
        TrybpracyButtonGroup  matlab.ui.container.ButtonGroup
        Button3               matlab.ui.control.RadioButton
        NasuchButton          matlab.ui.control.RadioButton
        StandardButton        matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        MainApp % Description
    end
    
    properties (Access = public)
        Mode CommunicationTypes=CommunicationTypes.SpontaneousUDP% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainApp=mainApp;
            app.WysyaniePanel.Enable="off";
        end

        % Button pushed function: DodajButton
        function DodajButtonPushed(app, event)
            IsIpValid=false;
            IsIdValid=false;
            IsPortValid=false;
            [Num, N] = sscanf(app.IpEditField.Value, '%d.%d.%d.%d');
            if N==4 && (all(Num))>=0 && (all(Num))<256
                IsIpValid=true;
            else
                IsIpValid=false;
                app.IpEditField.FontColor=[1.00,0.00,0.00];
            end

            if  ~isempty(app.IdEditField.Value) && ~isKey(app.MainApp.DataProvider.ListPMU,app.IdEditField.Value)
                IsIdValid=true;
            else
                IsIdValid=false;
                app.IdEditField.FontColor=[1.00,0.00,0.00];
            end

            if ~isempty(app.PortEditField.Value)
                IsPortValid=true;
            else
                IsPortValid=false;
                app.PortEditField.FontColor=[1.00,0.00,0.00];
            end

            if app.Mode==CommunicationTypes.CommandedUDP
                [Num, N] = sscanf(app.IpEditField_2.Value, '%d.%d.%d.%d');
                if N==4 && (all(Num))>=0 && (all(Num))<256
                    IsIpValid=true;
                else
                    IsIpValid=false;
                    app.IpEditField_2.FontColor=[1.00,0.00,0.00];
                end
                if ~isempty(app.PortEditField_2.Value)
                    IsPortValid=true;
                else
                    IsPortValid=false;
                    app.PortEditField_2.FontColor=[1.00,0.00,0.00];
                end
            end


            if IsIpValid==true && IsIdValid==true && IsPortValid==true
                app.MainApp.DataProvider.AddNewPMU(PMU(app.IpEditField.Value,uint16(app.PortEditField.Value),uint16(app.IdEditField.Value),"CommunicationType",app.Mode));
                delete(app);
            end
        end

        % Value changed function: IpEditField
        function IpEditFieldValueChanged(app, event)
            value = app.IpEditField.Value;
            app.IpEditField.FontColor=[0.00,0.00,0.00];
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            app.PortEditField.FontColor=[0.00,0.00,0.00];
        end

        % Value changed function: IdEditField
        function IdEditFieldValueChanged(app, event)
            value = app.IdEditField.Value;
            app.IdEditField.FontColor=[0.00,0.00,0.00];
        end

        % Selection changed function: TrybpracyButtonGroup
        function TrybpracyButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrybpracyButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Nasłuch"
                    app.WysyaniePanel.Enable="off";
                    app.Mode=CommunicationTypes.SpontaneousUDP;
                case "Standard"
                    app.WysyaniePanel.Enable="on";
                    app.Mode=CommunicationTypes.CommandedUDP;
            end
        end

        % Value changed function: PortEditField_2
        function PortEditField_2ValueChanged(app, event)
            app.PortEditField_2.FontColor=[0.00,0.00,0.00];
        end

        % Value changed function: IpEditField_2
        function IpEditField_2ValueChanged(app, event)
            app.IpEditField_2.FontColor=[0.00,0.00,0.00];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TrybpracyButtonGroup = uibuttongroup(app.GridLayout);
            app.TrybpracyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrybpracyButtonGroupSelectionChanged, true);
            app.TrybpracyButtonGroup.Title = 'Tryb pracy';
            app.StandardButton = uiradiobutton(app.TrybpracyButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.NasuchButton = uiradiobutton(app.TrybpracyButtonGroup);
            app.NasuchButton.Text = 'Nasłuch';
            app.NasuchButton.Value = true;
            app.Button3 = uiradiobutton(app.TrybpracyButtonGroup);
            app.Button3.Enable = 'off';
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OdbirPanel = uipanel(app.GridLayout2);
            app.OdbirPanel.Title = 'Odbiór';
            app.GridLayout3 = uigridlayout(app.OdbirPanel);
            app.PortEditFieldLabel = uilabel(app.GridLayout3);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PortEditField.Limits = [1 65535];
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 1;
            app.IpEditFieldLabel = uilabel(app.GridLayout3);
            app.IpEditFieldLabel.Text = 'Ip';
            app.IpEditField = uieditfield(app.GridLayout3, 'text');
            app.IpEditField.ValueChangedFcn = createCallbackFcn(app, @IpEditFieldValueChanged, true);
            app.IpEditField.Placeholder = '127.0.0.1';
            app.WysyaniePanel = uipanel(app.GridLayout2);
            app.WysyaniePanel.Title = 'Wysyłanie';
            app.GridLayout4 = uigridlayout(app.WysyaniePanel);
            app.PortEditField_2Label = uilabel(app.GridLayout4);
            app.PortEditField_2Label.Text = 'Port';
            app.PortEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.PortEditField_2.Limits = [1 65535];
            app.PortEditField_2.ValueDisplayFormat = '%.0f';
            app.PortEditField_2.ValueChangedFcn = createCallbackFcn(app, @PortEditField_2ValueChanged, true);
            app.PortEditField_2.Value = 1;
            app.IpEditField_2Label = uilabel(app.GridLayout4);
            app.IpEditField_2Label.Text = 'Ip';
            app.IpEditField_2 = uieditfield(app.GridLayout4, 'text');
            app.IpEditField_2.ValueChangedFcn = createCallbackFcn(app, @IpEditField_2ValueChanged, true);
            app.IpEditField_2.Placeholder = '127.0.0.1';
            app.DodajButton = uibutton(app.GridLayout2, 'push');
            app.DodajButton.ButtonPushedFcn = createCallbackFcn(app, @DodajButtonPushed, true);
            app.DodajButton.Text = 'Dodaj';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.IdEditFieldLabel = uilabel(app.GridLayout5);
            app.IdEditFieldLabel.Text = 'Id';
            app.IdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.IdEditField.Limits = [1 65534];
            app.IdEditField.ValueDisplayFormat = '%.0f';
            app.IdEditField.ValueChangedFcn = createCallbackFcn(app, @IdEditFieldValueChanged, true);
            app.IdEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DodajUrzadzenieDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end