classdef MapDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        DeleteButton                    matlab.ui.control.Button
        GridLayout10                    matlab.ui.container.GridLayout
        ListOfAdded                     matlab.ui.control.DropDown
        ListadodanychsynchrofazorwDropDownLabel  matlab.ui.control.Label
        UsuurzdzenieLabel               matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        LatitudePointY                  matlab.ui.control.NumericEditField
        SzerokogeograficznapunktuEditFieldLabel  matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        LongitudePointX                 matlab.ui.control.NumericEditField
        DugogeograficznapunktuEditFieldLabel  matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        ListOfAvaliable                 matlab.ui.control.DropDown
        ListaSynchrofazorwDropDownLabel  matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        DodajurzdzenieLabel             matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        UstawButton_2                   matlab.ui.control.Button
        GridLayout9                     matlab.ui.container.GridLayout
        LongitudeX2                     matlab.ui.control.NumericEditField
        DugogeograficznaEditField_2Label  matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        LatitudeY2                      matlab.ui.control.NumericEditField
        SzerokogeograficznyEditFieldLabel  matlab.ui.control.Label
        LimitmapypunktdrugiLabel        matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        LongitudeX1                     matlab.ui.control.NumericEditField
        DugogeograficznaEditFieldLabel  matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        LatitudeY1                      matlab.ui.control.NumericEditField
        SzerokogeograficznaEditFieldLabel  matlab.ui.control.Label
        LimitmapypunktpierwszyLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        GeoAxes; % Description
        mainApp;
        MapOfAdded;
        LatLim1
        LatLim2
        LonLim1
        LonLim2
    end
    
    methods (Access = public)
        
        function UpdateMap(app)

            Latitude=[];
            Longitude=[];
            Angle=[];
            Magnitude=[];

            LatitudeLimit=[];
            LongitudeLimit=[];
            if ~isempty(app.LatLim1) && ~isempty(app.LatLim2) && ~isempty(app.LonLim1) && ~isempty(app.LonLim2)
                LatitudeLimit=[app.LatLim1 app.LatLim2];
                LongitudeLimit=[app.LonLim1 app.LonLim2];
            end

            k0=keys(app.MapOfAdded);
            for i=1:size(app.MapOfAdded)
               key0=char(cell2mat(k0(i)));
               handleToSubMap=app.MapOfAdded(key0);
               handle=app.mainApp.DataProvider.PolarPlotsMap(handleToSubMap.ID);
               handle1=handle(handleToSubMap.STN);

               Latitude(end+1)=handleToSubMap.Latitude;
               Longitude(end+1)=handleToSubMap.Longitude;
               Angle(end+1)=handle1.ang;
               Magnitude(end+1)=handle1.mag;
            end
            DrawOnGeoPlot(app.GeoAxes,Latitude,Longitude,Angle,Magnitude,LatitudeLimit,LongitudeLimit);
        end

        function UpdateLists(app)
            k=1;
            items=cell(1,k);
            k0=keys(app.mainApp.DataProvider.PolarPlotsMap);
            for i=1:size(app.mainApp.DataProvider.PolarPlotsMap)
               key0=uint32(cell2mat(k0(i)));
               handleToSubMap=app.mainApp.DataProvider.PolarPlotsMap(key0);
               k1=keys(handleToSubMap);
               for j=1:size(handleToSubMap)
                    key1=char(cell2mat(k1(j)));
                    key2=char(string(key0)+"-"+string(key1));
                    
                    if ~isKey(app.MapOfAdded,key2)
                        items{1,k}=key2;
                        k=k+1;
                    end
               end
            end
            
            if size(items,2)>0 && size(items{1,1},2)>0
                app.ListOfAvaliable.Items=items;
            else
                app.ListOfAvaliable.Items={};
            end

            k=1;
            items=cell(1,k);
            k0=keys(app.MapOfAdded);
            for i=1:size(app.MapOfAdded)
               key0=char(cell2mat(k0(j)));
               items{1,k}=key0;
               k=k+1;
            end
            
            app.ListOfAdded.Items=items;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.GeoAxes=geoaxes();
            app.mainApp=mainApp;

            app.MapOfAdded = containers.Map("KeyType",'char','ValueType','any');
            
            app.UpdateLists();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            Selected=app.ListOfAdded.Value;
            if isKey(app.MapOfAdded,Selected)
                remove(app.MapOfAdded,Selected);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.LatitudePointY.Value~=0 && app.LongitudePointX.Value~=0
                location=strfind(app.ListOfAvaliable.Value,"-");
                id=uint32(str2num(char(cell2mat(extractBetween(app.ListOfAvaliable.Value,1,location(1)-1)))));
                stn=char(cell2mat(extractBetween(app.ListOfAvaliable.Value,location(1)+1,size(app.ListOfAvaliable.Value,2))));
                handle=GeoPlotData(app.LatitudePointY.Value,app.LongitudePointX.Value,id,stn(1,:));
                app.MapOfAdded(char(app.ListOfAvaliable.Value))=handle;
                app.UpdateLists();
                app.LatitudePointY.Value=0;
                app.LongitudePointX.Value=0;
            end
        end

        % Button pushed function: UstawButton_2
        function UstawButton_2Pushed(app, event)
            if app.LatitudeY1.Value<app.LatitudeY2.Value && app.LongitudeX1.Value<app.LongitudeX2.Value
                app.LatLim2=app.LatitudeY2.Value;
                app.LonLim2=app.LongitudeX2.Value;
                app.LatLim1 =app.LatitudeY1.Value;
                app.LonLim1 =app.LongitudeX1.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LimitmapypunktpierwszyLabel = uilabel(app.GridLayout2);
            app.LimitmapypunktpierwszyLabel.Text = 'Limit mapy punkt pierwszy';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.SzerokogeograficznaEditFieldLabel = uilabel(app.GridLayout6);
            app.SzerokogeograficznaEditFieldLabel.Text = 'Szerokość geograficzna';
            app.LatitudeY1 = uieditfield(app.GridLayout6, 'numeric');
            app.LatitudeY1.Limits = [-90 90];
            app.LatitudeY1.ValueDisplayFormat = '%.10f';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.DugogeograficznaEditFieldLabel = uilabel(app.GridLayout7);
            app.DugogeograficznaEditFieldLabel.Text = 'Długość geograficzna';
            app.LongitudeX1 = uieditfield(app.GridLayout7, 'numeric');
            app.LongitudeX1.Limits = [-180 180];
            app.LongitudeX1.ValueDisplayFormat = '%.10f';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.LimitmapypunktdrugiLabel = uilabel(app.GridLayout3);
            app.LimitmapypunktdrugiLabel.Text = 'Limit mapy punkt drugi';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.SzerokogeograficznyEditFieldLabel = uilabel(app.GridLayout8);
            app.SzerokogeograficznyEditFieldLabel.Text = 'Szerokość geograficzny';
            app.LatitudeY2 = uieditfield(app.GridLayout8, 'numeric');
            app.LatitudeY2.Limits = [-90 90];
            app.LatitudeY2.ValueDisplayFormat = '%.10f';
            app.GridLayout9 = uigridlayout(app.GridLayout3);
            app.DugogeograficznaEditField_2Label = uilabel(app.GridLayout9);
            app.DugogeograficznaEditField_2Label.Text = 'Długość geograficzna';
            app.LongitudeX2 = uieditfield(app.GridLayout9, 'numeric');
            app.LongitudeX2.Limits = [-180 180];
            app.LongitudeX2.ValueDisplayFormat = '%.10f';
            app.UstawButton_2 = uibutton(app.GridLayout3, 'push');
            app.UstawButton_2.ButtonPushedFcn = createCallbackFcn(app, @UstawButton_2Pushed, true);
            app.UstawButton_2.Text = 'Ustaw';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.DodajurzdzenieLabel = uilabel(app.GridLayout4);
            app.DodajurzdzenieLabel.Text = 'Dodaj urządzenie';
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Dodaj';
            app.GridLayout12 = uigridlayout(app.GridLayout4);
            app.ListaSynchrofazorwDropDownLabel = uilabel(app.GridLayout12);
            app.ListaSynchrofazorwDropDownLabel.Text = 'Lista Synchrofazorów';
            app.ListOfAvaliable = uidropdown(app.GridLayout12);
            app.ListOfAvaliable.Items = {};
            app.ListOfAvaliable.Value = {};
            app.GridLayout13 = uigridlayout(app.GridLayout4);
            app.DugogeograficznapunktuEditFieldLabel = uilabel(app.GridLayout13);
            app.DugogeograficznapunktuEditFieldLabel.Text = 'Długość geograficzna punktu';
            app.LongitudePointX = uieditfield(app.GridLayout13, 'numeric');
            app.LongitudePointX.Limits = [-180 180];
            app.LongitudePointX.ValueDisplayFormat = '%.10f';
            app.GridLayout14 = uigridlayout(app.GridLayout4);
            app.SzerokogeograficznapunktuEditFieldLabel = uilabel(app.GridLayout14);
            app.SzerokogeograficznapunktuEditFieldLabel.Text = 'Szerokość geograficzna punktu';
            app.LatitudePointY = uieditfield(app.GridLayout14, 'numeric');
            app.LatitudePointY.Limits = [-90 90];
            app.LatitudePointY.ValueDisplayFormat = '%.10f';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.UsuurzdzenieLabel = uilabel(app.GridLayout5);
            app.UsuurzdzenieLabel.Text = 'Usuń urządzenie';
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.ListadodanychsynchrofazorwDropDownLabel = uilabel(app.GridLayout10);
            app.ListadodanychsynchrofazorwDropDownLabel.Text = 'Lista dodanych synchrofazorów';
            app.ListOfAdded = uidropdown(app.GridLayout10);
            app.ListOfAdded.Items = {''};
            app.ListOfAdded.Value = '';
            app.DeleteButton = uibutton(app.GridLayout5, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Usuń';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end