classdef UstawieniaDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        ZmieButton   matlab.ui.control.Button
        GridLayout3  matlab.ui.container.GridLayout
        GridLayout2  matlab.ui.container.GridLayout
        CoilesekundodwieawykresySpinner  matlab.ui.control.Spinner
        CoilesekundodwieawykresySpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.CoilesekundodwieawykresySpinner.Value=mainapp.RefreshRate;
        end

        % Button pushed function: ZmieButton
        function ZmieButtonPushed(app, event)
            app.MainApp.RefreshRate=app.CoilesekundodwieawykresySpinner.Value;
            stop(app.MainApp.Timer);
            app.MainApp.Timer=app.MainApp.InitTimer();
            start(app.MainApp.Timer);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CoilesekundodwieawykresySpinnerLabel = uilabel(app.GridLayout2);
            app.CoilesekundodwieawykresySpinnerLabel.Text = 'Co ile sekund odświeżać wykresy';
            app.CoilesekundodwieawykresySpinner = uispinner(app.GridLayout2);
            app.CoilesekundodwieawykresySpinner.Limits = [1 60];
            app.CoilesekundodwieawykresySpinner.ValueDisplayFormat = '%.0f';
            app.CoilesekundodwieawykresySpinner.Value = 1;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ZmieButton = uibutton(app.GridLayout, 'push');
            app.ZmieButton.ButtonPushedFcn = createCallbackFcn(app, @ZmieButtonPushed, true);
            app.ZmieButton.Text = 'Zmień';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UstawieniaDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end