classdef UsunUrzadzenieDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        DeleteButton  matlab.ui.control.Button
        GridLayout2   matlab.ui.container.GridLayout
        DeviceList    matlab.ui.control.DropDown
        UrzdzeniedousuniciaDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp=mainApp;

            str=keys(mainApp.DataProvider.ListPMU);
            
            [~,columns]=size(str);
            items=cell(1,columns);
            for i=1:columns
                items{1,i}=num2str(cell2mat(str(1,i)));
            end
            app.DeviceList.Items=items;

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            stop(app.mainApp.Timer)
            app.mainApp.DataProvider.DeletePMU(uint32(str2double(app.DeviceList.Value)));
            start(app.mainApp.Timer)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UrzdzeniedousuniciaDropDownLabel = uilabel(app.GridLayout2);
            app.UrzdzeniedousuniciaDropDownLabel.Text = 'Urządzenie do usunięcia';
            app.DeviceList = uidropdown(app.GridLayout2);
            app.DeviceList.Items = {'cos1', 'cos2', 'cos3'};
            app.DeviceList.Value = 'cos1';
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Usuń';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UsunUrzadzenieDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end